classdef MccCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MccCalculatorUIFigure      matlab.ui.Figure
        Layout_All                 matlab.ui.container.GridLayout
        SettingsPanel              matlab.ui.container.Panel
        GridLayout_Setting         matlab.ui.container.GridLayout
        VetDimEdit_px              matlab.ui.control.NumericEditField
        VetDimEdit_mm              matlab.ui.control.NumericEditField
        VerticalDimensionLabel     matlab.ui.control.Label
        HorDimEdit_px              matlab.ui.control.NumericEditField
        HorDimEdit_mm              matlab.ui.control.NumericEditField
        pxLabel                    matlab.ui.control.Label
        pxLabel_2                  matlab.ui.control.Label
        mmLabel                    matlab.ui.control.Label
        HorizontalDimensionEditFieldLabel  matlab.ui.control.Label
        mmLabel_2                  matlab.ui.control.Label
        RulerUnitButtonGroup       matlab.ui.container.ButtonGroup
        MicrometerumButton         matlab.ui.control.RadioButton
        PixelButton                matlab.ui.control.RadioButton
        EditFPS                    matlab.ui.control.NumericEditField
        FPSLabel                   matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        PreprocessTab              matlab.ui.container.Tab
        GridLayout_Process         matlab.ui.container.GridLayout
        RangePanel                 matlab.ui.container.Panel
        GridLayout_Range           matlab.ui.container.GridLayout
        SetButton                  matlab.ui.control.Button
        EditStopAt                 matlab.ui.control.NumericEditField
        StopAtEditField_2Label     matlab.ui.control.Label
        EditStartFrom              matlab.ui.control.NumericEditField
        StartFromEditField_2Label  matlab.ui.control.Label
        GridLayout_ProcessInfo     matlab.ui.container.GridLayout
        Drop_RegType               matlab.ui.control.DropDown
        TypeLabel                  matlab.ui.control.Label
        Check_AutoIndex            matlab.ui.control.CheckBox
        Check_AutoThres            matlab.ui.control.CheckBox
        Text_Thres                 matlab.ui.control.TextArea
        IndexLabel_2               matlab.ui.control.Label
        Text_RefIndex              matlab.ui.control.TextArea
        IndexLabel_3               matlab.ui.control.Label
        GridLayout_ProcessBtn      matlab.ui.container.GridLayout
        Button_Register            matlab.ui.control.Button
        Button_Filter              matlab.ui.control.Button
        Button_Crop                matlab.ui.control.Button
        Button_Select_Process      matlab.ui.control.Button
        RegionDrop_Process         matlab.ui.control.DropDown
        RegionAxes_Process         matlab.ui.control.UIAxes
        ResultAxes_Process         matlab.ui.control.UIAxes
        CBFTab                     matlab.ui.container.Tab
        GridLayout_CBF             matlab.ui.container.GridLayout
        TextArea_CBF               matlab.ui.control.TextArea
        Layout_Background          matlab.ui.container.GridLayout
        RegionDrop_Background      matlab.ui.control.DropDown
        Button_Region_Background   matlab.ui.control.Button
        RegionAxes_Background      matlab.ui.control.UIAxes
        Layout_Cillary             matlab.ui.container.GridLayout
        RegionDrop_Cillary         matlab.ui.control.DropDown
        Button_Region_Cillary      matlab.ui.control.Button
        RegionAxes_Cillary         matlab.ui.control.UIAxes
        GridLayout_CBF_Buttons     matlab.ui.container.GridLayout
        FigureCheckBox             matlab.ui.control.CheckBox
        TextArea_Reduction         matlab.ui.control.NumericEditField
        RecutionLabel              matlab.ui.control.Label
        SmoothCheckBox             matlab.ui.control.CheckBox
        CalcButton_CBF             matlab.ui.control.Button
        RecordButton               matlab.ui.control.Button
        FigureButton               matlab.ui.control.Button
        UIAxes_CBF                 matlab.ui.control.UIAxes
        MCTTab                     matlab.ui.container.Tab
        GridLayout_MCT             matlab.ui.container.GridLayout
        GridLayout_Buttons_MCT     matlab.ui.container.GridLayout
        Button_Record_MCT          matlab.ui.control.Button
        Button_Fig_MCT             matlab.ui.control.Button
        Button_Calc_MCT            matlab.ui.control.Button
        GridLayout_MCTInfo         matlab.ui.container.GridLayout
        TextArea_MCT               matlab.ui.control.TextArea
        FlowrateLabel              matlab.ui.control.Label
        EditMCTScale               matlab.ui.control.NumericEditField
        ScaleLabel                 matlab.ui.control.Label
        TextArea_Result            matlab.ui.control.TextArea
        ResultLabel                matlab.ui.control.Label
        TextArea_Direction         matlab.ui.control.TextArea
        ResultLabel_2              matlab.ui.control.Label
        RegionDrop_MCT             matlab.ui.control.DropDown
        Button_Select_MCT          matlab.ui.control.Button
        UIAxes_MCT                 matlab.ui.control.UIAxes
        RegionAxes_MCT             matlab.ui.control.UIAxes
        SegmentTab                 matlab.ui.container.Tab
        GridLayout_Flatten         matlab.ui.container.GridLayout
        EditSegOffset              matlab.ui.control.NumericEditField
        ScaleLabel_2               matlab.ui.control.Label
        EditAuxLine                matlab.ui.control.NumericEditField
        AuxlineCheckBox            matlab.ui.control.CheckBox
        TabGroup_SegResult         matlab.ui.container.TabGroup
        FramesTab                  matlab.ui.container.Tab
        FrameTable                 matlab.ui.control.Table
        PointsTab                  matlab.ui.container.Tab
        PointsTable                matlab.ui.control.Table
        EditScale                  matlab.ui.control.NumericEditField
        ScaleCheckBox              matlab.ui.control.CheckBox
        SaveSegRes                 matlab.ui.control.Button
        ImageList_Seg              matlab.ui.container.Panel
        GridLayout_ImList2         matlab.ui.container.GridLayout
        Set1Button                 matlab.ui.control.StateButton
        Set2Button                 matlab.ui.control.StateButton
        FlattenButton              matlab.ui.control.Button
        SegmentButton              matlab.ui.control.Button
        DrawLayerButton            matlab.ui.control.Button
        ASLTab                     matlab.ui.container.Tab
        MucusBMTab                 matlab.ui.container.Tab
        EpithelialTab              matlab.ui.container.Tab
        CBPTab                     matlab.ui.container.Tab
        PCLTab                     matlab.ui.container.Tab
        CoverageTab                matlab.ui.container.Tab
        CPBTab                     matlab.ui.container.Tab
        PTLTab                     matlab.ui.container.Tab
        ImageList                  matlab.ui.container.ButtonGroup
        GridLayout_ImList          matlab.ui.container.GridLayout
        RangeButton                matlab.ui.control.StateButton
        RegistrationButton         matlab.ui.control.StateButton
        FilteringButton            matlab.ui.control.StateButton
        CropButton                 matlab.ui.control.StateButton
        OriginalButton             matlab.ui.control.StateButton
        ExportButton               matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        PlayButton                 matlab.ui.control.StateButton
        Spinner                    matlab.ui.control.Spinner
        Slider                     matlab.ui.control.Slider
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Playing         = 0;    % 记录图像是否正在播放
        CurrImSet       = {};   % 图片集 - 正在展示
        Im_Original     = {};   % 图片集 - 初始加载
        Im_Range        = {};   % 图片集 - 范围选择
        Im_Crop         = {};   % 图片集 - Cropped
        Im_Filtered     = {};   % 图片集 - Filtered
        Im_Registered   = {};   % 图片集 - 配准

        Segment_Orginl  = {};   % 图片集 - 初始分割
        Segment_Images  = {};   % 图片集 - 分割完成
        Segment_Flatten = {};   % 图片集 - 拉平
        Segment_Layers  = {};   % 分割结果

        FolderPath;             % 记录上次打开的文件路径
        ImName;                 % 记录当前的图片集名称
        Region;                 % 主框选区域
        RegionBack;             % 背景框选区域 （CBF计算）
        
        DimFactorX = 1;
        DimFactorY = 1;
        LayerLine;
        LayerLine_Adjust;
        
        MccLogDlg;              % Logger弹窗
        CBFPack;                % 记录并传递CBF计算的结果包
        MCTPack;                % 记录并传递MCT计算的结果包
    end
    
    methods (Access = private)
        
        function PlayingImages(app)
            imIdx  = uint16(app.Spinner.Value);
            frames = numel(app.CurrImSet);

            if imIdx == frames
                imIdx = 1;
            end

            for i=imIdx:frames
                if app.Playing == 0
                    break;
                end
                app.DispIm(app.UIAxes, app.CurrImSet{i});
                app.Slider.Value  = double(i);
                app.Spinner.Value = double(i);
                drawnow;
            end

            app.PlayButton.Text  = "Play";
            app.PlayButton.Value = 0;
            app.Playing          = 0;
        end
        
        function RegionChanged_Process(app,~,~)
            imIdx = uint16(app.Spinner.Value);
            [result, SelectedIm] = app.CropIm(app.CurrImSet{imIdx}, app.Region);
            if true == result
                app.DispIm(app.RegionAxes_Process, SelectedIm);
            end
            drawnow;
        end

        function RegionChanged_Cillary(app,~,~)
            imIdx = uint16(app.Spinner.Value);
            [result, SelectedIm] = app.CropIm(app.CurrImSet{imIdx}, app.Region);
            if true == result
                app.DispIm(app.RegionAxes_Cillary, SelectedIm);
            end
            drawnow;
        end

        function RegionChanged_Background(app,~,~)
            imIdx = uint16(app.Spinner.Value);
            [result, SelectedIm] = app.CropIm(app.CurrImSet{imIdx}, app.RegionBack);
            if true == result
                app.DispIm(app.RegionAxes_Background, SelectedIm);
            end
            drawnow;
        end

        function RegionChanged_MCT(app,~,~)
            imIdx = uint16(app.Spinner.Value);
            [result, SelectedIm] = app.CropIm(app.CurrImSet{imIdx}, app.Region);
            if true == result
                app.DispIm(app.RegionAxes_MCT, SelectedIm);
            end
            drawnow;
        end

        function [Result, SelectedIm] = CropIm(~, Im, Region)
            if isempty(Region) || ~ishandle(Region)
                Result = false;
                SelectedIm = [];
                return;
            end

            if isa(Region, 'images.roi.Rectangle')
                SelectedIm = imcrop( Im, round(Region.Position) );
                Result = true;
            elseif isa(Region, 'images.roi.Polygon')
                xi = Region.Position(:,1);
                yi = Region.Position(:,2);
                
                dim = size(Im);
    
                BW = roipoly( Im, xi, yi );
                SelectedIm = bsxfun(@times, Im, uint8(BW));
                
                left   = min(xi);
                right  = max(xi);
                top    = min(yi);
                bottom = max(yi);
    
                left   = int16(max(left, 1));
                right  = int16(min(right, dim(2)));
                top    = int16(max(top, 1));
                bottom = int16(min(bottom, dim(1)));
    
                SelectedIm = SelectedIm(top:bottom,left:right,:);
    
                Result = true;
            else
                keyboard;
            end
        end

        function DispIm(~, comp, Image)
            dimension = ndims(Image);
            if dimension == 2
                Image = cat(3, Image, Image, Image);
                imagesc(comp, Image);
            elseif dimension == 3
                imagesc(comp, Image);
            elseif dimension == 4
                keyboard;
            else
                keyboard;
            end
        end
        
        function UpdateTickLabels(app, axis)
            if strcmp(axis, 'X')
                xticks = app.UIAxes.XTick;
                xticklabels = arrayfun(@(x) num2str(int16(x*app.DimFactorX)), xticks, 'UniformOutput', false);
                app.UIAxes.XTickLabel = xticklabels;
            else % 'Y'
                yticks = app.UIAxes.YTick;
                yticklabels = arrayfun(@(y) num2str(int16(y*app.DimFactorY)), yticks, 'UniformOutput', false);
                app.UIAxes.YTickLabel = yticklabels;
            end
        end
        
        function [fig, wb] = CreateWaitbar(app, title)
            waitbarWidth  = 400;
            waitbarHeight = 45;
            left  = app.MccCalculatorUIFigure.Position(1)+app.MccCalculatorUIFigure.Position(3)/2-waitbarWidth/2;
            right = app.MccCalculatorUIFigure.Position(2)+app.MccCalculatorUIFigure.Position(4)/2-waitbarHeight/2;
            fig = uifigure('NumberTitle', 'off', 'MenuBar', 'none', 'ToolBar', 'none', 'Resize', 'off', 'Name', title, ...
                           'Position', [left, right, waitbarWidth, waitbarHeight], 'WindowStyle','modal');
            wb = uiprogressdlg(fig);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadImage(app, event)
            app.FolderPath = uigetdir(app.FolderPath);
            if isequal(app.FolderPath, 0)
                app.FolderPath = '';
                return;
            end

            files = dir(fullfile(app.FolderPath, '*.bmp'));
            if ~isempty(files)
                [files,~,~] = natsortfiles(files);
                img = imread( fullfile(app.FolderPath, files(1).name) );
                [heightRef, widthRef, ~] = size(img);

                app.DispIm(app.UIAxes, img);
                axis(app.UIAxes, 'tight');
                
                addlistener(app.UIAxes, 'XLim', 'PostSet', @(src, event) app.UpdateTickLabels('X'));
                addlistener(app.UIAxes, 'YLim', 'PostSet', @(src, event) app.UpdateTickLabels('Y'));

                [directory, lastPart, ~] = fileparts(app.FolderPath);
                app.MccCalculatorUIFigure.Name = ['Mcc Calculator - ' lastPart];
                app.ImName = lastPart;
            else
                warndlg('No bitmap file(s) found!', 'Warning');
                return;
            end

            app.Slider.Value   = 1;
            app.Spinner.Value  = 1;
            app.Im_Original    = {};

            frames = length(files);
            [fig,bar] = app.CreateWaitbar('Loading image(s)...');
            for i=1:frames
                img = imread( fullfile(app.FolderPath, files(i).name) );
                [height, width, channel] = size(img);

                if height ~= heightRef || width ~= widthRef
                    warndlg('Wrong image size detected!', 'Warning');
                    cla(app.UIAxes);
                    close(fig);
                    return;
                end

                if channel == 1
                    app.Im_Original{i} = cat(3, img, img, img);
                else
                    app.Im_Original{i} = img;
                end

                bar.Value = i/frames;
            end
            close(fig);

            app.CurrImSet           = app.Im_Original;
            app.PlayButton.Enable   = "on";
            app.ExportButton.Enable = "on";
            app.Slider.Enable       = "on";
            app.Spinner.Enable      = "on";

            app.Slider.Limits  = [1 frames];
            app.Spinner.Limits = [1 frames];

            app.OriginalButton.Enable     = "on";
            app.CropButton.Enable         = "off";
            app.FilteringButton.Enable    = "off";
            app.RegistrationButton.Enable = "off";
            app.OriginalButton.Value      = 1;
            app.CropButton.Value          = 0;
            app.FilteringButton.Value     = 0;
            app.RegistrationButton.Value  = 0;

            app.FolderPath = directory;
        end

        % Button pushed function: ExportButton
        function ExportImage(app, event)
            outputFolder = uigetdir('', 'Select export path');
            if outputFolder == 0
                return;
            end

            [fig,bar] = app.CreateWaitbar("Saving image file(s) ...");
            for idx = 1:numel(app.CurrImSet)
                filename = sprintf('%d.bmp', idx);
                imwrite(app.CurrImSet{idx}, fullfile(outputFolder, filename), 'bmp');
                bar.Value = idx/numel(app.CurrImSet);
            end
            close(fig);

            msgbox('Images export successfully!', 'Info');
        end

        % Value changed function: PlayButton
        function PlayImages(app, event)
            app.Playing = app.PlayButton.Value;
            if 1 == app.Playing
                app.PlayButton.Text = "Pause";
                app.PlayingImages();
            else
                app.PlayButton.Text = "Play";
            end
        end

        % Value changing function: Slider
        function SliderChanging(app, event)
            imIdx = int16(event.Value);
            app.DispIm(app.UIAxes, app.CurrImSet{imIdx});
            app.Spinner.Value = event.Value;
            drawnow;
        end

        % Value changed function: Spinner
        function SpinnerChanged(app, event)
            imIdx = int16(app.Spinner.Value);
            app.DispIm(app.UIAxes, app.CurrImSet{imIdx});
            app.Slider.Value = app.Spinner.Value;
        end

        % Value changed function: CropButton, FilteringButton, 
        % ...and 5 other components
        function ImSetChanged(app, event)
            app.OriginalButton.Value      = 0;
            app.RangeButton.Value         = 0;
            app.CropButton.Value          = 0;
            app.FilteringButton.Value     = 0;
            app.RegistrationButton.Value  = 0;
            app.Set1Button.Value          = 0;
            app.Set2Button.Value          = 0;
            
            switch event.Source
                case app.OriginalButton
                    app.CurrImSet = app.Im_Original;
                    app.OriginalButton.Value = 1;
                case app.RangeButton
                    app.CurrImSet = app.Im_Range;
                    app.RangeButton.Value = 1;
                case app.CropButton
                    app.CurrImSet = app.Im_Crop;
                    app.CropButton.Value = 1;
                case app.FilteringButton
                    app.CurrImSet = app.Im_Filtered;
                    app.FilteringButton.Value = 1;
                case app.RegistrationButton
                    app.CurrImSet = app.Im_Registered;
                    app.RegistrationButton.Value = 1;
                case app.Set1Button
                    app.CurrImSet = app.Segment_Images;
                    app.Set1Button.Value = 1;
                case app.Set2Button
                    app.CurrImSet = app.Segment_Flatten;
                    app.Set2Button.Value = 1;
                otherwise
                    disp("error");
            end

            app.DispIm(app.UIAxes, app.CurrImSet{1});
            app.Slider.Value   = 1;
            app.Spinner.Value  = 1;
            app.Slider.Limits  = [1 numel(app.CurrImSet)];
            app.Spinner.Limits = [1 numel(app.CurrImSet)];
        end

        % Button pushed function: Button_Region_Background, 
        % ...and 3 other components
        function SelectRegion(app, event)
            if isempty(app.CurrImSet)
                msgbox('No images loaded!', '', 'error');
                return;
            end

            switch event.Source.Tag
                case 'Process'
                    if ~isempty(app.Region)
                        delete(app.Region);
                    end

                    if ~isempty(app.RegionBack)
                        delete(app.RegionBack);
                    end

                    if strcmp('Polygon', strrep(app.RegionDrop_Process.Value, ' ', '')) % 去空格
                        app.Region = drawpolygon(app.UIAxes);
                    else
                        app.Region = drawrectangle(app.UIAxes);
                        if app.Region.Position(3) == 0 || app.Region.Position(4) == 0
                            delete(app.Region); % Invalid region
                            return;
                        end
                    end

                    addlistener(app.Region, 'MovingROI', @app.RegionChanged_Process);
                    app.RegionChanged_Process();
                    axis(app.RegionAxes_Process, 'tight');
                case 'Cillary'
                    if ~isempty(app.Region)
                        delete(app.Region);
                    end

                    if strcmp('Polygon', strrep(app.RegionDrop_Cillary.Value, ' ', ''))
                        app.Region = drawpolygon(app.UIAxes);
                    else
                        app.Region = drawrectangle(app.UIAxes);
                        if app.Region.Position(3) == 0 || app.Region.Position(4) == 0
                            delete(app.Region); % Invalid region
                            return;
                        end
                    end

                    addlistener(app.Region, 'MovingROI', @app.RegionChanged_Cillary);
                    app.RegionChanged_Cillary();
                    axis(app.RegionAxes_Cillary, 'tight');
                case 'Background'
                    if ~isempty(app.RegionBack)
                        delete(app.RegionBack);
                    end

                    if strcmp('Polygon', strrep(app.RegionDrop_Background.Value, ' ', ''))
                        app.RegionBack = drawpolygon(app.UIAxes, 'Color', 'red');
                    else
                        app.RegionBack = drawrectangle(app.UIAxes, 'Color', 'red');
                        if app.RegionBack.Position(3) == 0 || app.RegionBack.Position(4) == 0
                            delete(app.RegionBack); % Invalid region
                            return;
                        end
                    end

                    addlistener(app.RegionBack, 'MovingROI', @app.RegionChanged_Background);
                    app.RegionChanged_Background();
                    axis(app.RegionAxes_Background, 'tight');
                case 'MCT'
                    if ~isempty(app.Region)
                        delete(app.Region);
                    end

                    if ~isempty(app.RegionBack)
                        delete(app.RegionBack);
                    end

                    if strcmp('Polygon', strrep(app.RegionDrop_MCT.Value, ' ', ''))
                        app.Region = drawpolygon(app.UIAxes);
                    else
                        app.Region = drawrectangle(app.UIAxes);
                        if app.Region.Position(3) == 0 || app.Region.Position(4) == 0
                            delete(app.Region); % Invalid region
                            return;
                        end
                    end
                    addlistener(app.Region, 'MovingROI', @app.RegionChanged_MCT);
                    app.RegionChanged_MCT();
                    axis(app.RegionAxes_MCT, 'tight');
            end
        end

        % Button pushed function: SetButton
        function SetImRange(app, event)
            if isempty(app.CurrImSet)
                msgbox('No images loaded!', '', 'error');
                return;
            end

            startPic = app.EditStartFrom.Value;
            stopPic  = app.EditStopAt.Value;

            if 0 == startPic || 0 == stopPic
                msgbox('Invalid range!', '', 'error');
                return;
            end

            if stopPic <= startPic || stopPic > numel(app.CurrImSet)
                msgbox('Invalid range!', '', 'error');
                return;
            end

            app.Im_Range = app.CurrImSet(startPic:stopPic);

            app.CurrImSet = app.Im_Range;

            app.Slider.Limits            = [1 numel(app.Im_Range)];
            app.Spinner.Limits           = [1 numel(app.Im_Range)];
            app.Slider.Value             = 1;
            app.Spinner.Value            = 1;
            app.RangeButton.Value        = 1;
            app.CropButton.Value         = 0;
            app.OriginalButton.Value     = 0;
            app.FilteringButton.Value    = 0;
            app.RegistrationButton.Value = 0;
            app.RangeButton.Enable       = "on";

            app.DispIm(app.UIAxes, app.CurrImSet{1});
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: Button_Crop
        function CropImages(app, event)
            if isempty(app.Region) || ~ishandle(app.Region)
                msgbox('No region selected', '', 'error');
                return;
            end

            [fig,bar] = app.CreateWaitbar('Crop image(s)...');
            frames = numel(app.CurrImSet);
            for idx = 1:frames
                [~, SelectedIm] = app.CropIm(app.CurrImSet{idx}, app.Region);
                app.Im_Crop{idx} = SelectedIm;
                bar.Value = idx/frames;
            end
            close(fig);

            app.CurrImSet                = app.Im_Crop;
            app.Slider.Value             = 1;
            app.Spinner.Value            = 1;
            app.CropButton.Value         = 1;
            app.OriginalButton.Value     = 0;
            app.RangeButton.Value        = 0;
            app.FilteringButton.Value    = 0;
            app.RegistrationButton.Value = 0;
            app.CropButton.Enable        = "on";

            app.DispIm(app.UIAxes, app.CurrImSet{1});
            axis(app.UIAxes, 'tight');

            delete(app.Region);
        end

        % Button pushed function: Button_Filter
        function FilterImages(app, event)
            frames = numel(app.CurrImSet);
            if isempty(app.CurrImSet)
                msgbox('No images loaded!', '', 'error');
                return;
            end

            [fig,bar] = app.CreateWaitbar('Searching reference image...');
            if true == app.Check_AutoIndex.Value
                sum_images = double(rgb2gray(app.CurrImSet{1}));
                for idx = 2:frames
                    sum_images = sum_images + double(rgb2gray(app.CurrImSet{idx}));
                    bar.Value = idx/(frames*2);
                end
                average_image = uint8(sum_images / frames);
    
                correlation_values = zeros(1, frames);
                for idx = 1:frames
                    correlation_values(idx) = corr2(average_image, rgb2gray(app.CurrImSet{idx}));
                    bar.Value = (frames+idx)/(frames*2);
                end
    
                [~, refIdx] = max(correlation_values);
                app.Text_RefIndex.Value = sprintf( '%d', refIdx );
                drawnow;
            else
                refIdx = str2double(app.Text_RefIndex.Value);

                if isnan(refIdx)
                    msgbox('Invalid reference index', '', 'warn');
                    return;
                else
                    refIdx = int16(refIdx);
                end
            end
            close(fig);

            corrAll = zeros(1, frames);
            imBase  = rgb2gray(uint8(app.CurrImSet{refIdx}));
    
            % 执行相关性分析
            [fig,bar] = app.CreateWaitbar('Filtering in progress...');
            for idx = 1:frames
                image1 = imBase;
                image2 = rgb2gray(uint8(app.CurrImSet{idx}));
                
                correlation = corr2(image1, image2);
                corrAll(idx)  = correlation;

                bar.Value = idx/frames;
            end

            if true == app.Check_AutoThres.Value
                thres = mean(corrAll);
            else
                thres = str2double(app.Text_Thres.Value);
                if isnan(thres)
                    msgbox('Invalid threshold', '', 'warn');
                    return;
                end
            end
            close(fig);

            plot(app.ResultAxes_Process, corrAll);
            hold(app.ResultAxes_Process, 'on');
            plot(app.ResultAxes_Process,[0, frames], [thres, thres], 'r', 'LineWidth', 2);
            hold(app.ResultAxes_Process, 'off');
            app.Im_Filtered = app.CurrImSet(corrAll > thres);
            app.CurrImSet   = app.Im_Filtered;

            app.Slider.Limits  = [1 numel(app.Im_Filtered)];
            app.Spinner.Limits = [1 numel(app.Im_Filtered)];
            app.Slider.Value   = 1;
            app.Spinner.Value  = 1;

            app.DispIm(app.UIAxes, app.Im_Filtered{1});
            axis(app.UIAxes, 'tight');

            app.FilteringButton.Enable   = "on";
            app.FilteringButton.Value    = 1;
            app.CropButton.Value         = 0;
            app.OriginalButton.Value     = 0;
            app.RangeButton.Value        = 0;
            app.RegistrationButton.Value = 0;
            app.Text_Thres.Value         = sprintf( '%.2f', thres );
        end

        % Button pushed function: Button_Register
        function RegisterImages(app, event)
            frames = numel(app.CurrImSet);
            if isempty(app.CurrImSet)
                msgbox('No images loaded!', '', 'error');
                return;
            end

            [fig,bar] = app.CreateWaitbar('Searching reference image...');
            if true == app.Check_AutoIndex.Value
                sum_images = double(rgb2gray(app.CurrImSet{1}));
                for idx = 2:frames
                    sum_images = sum_images + double(rgb2gray(app.CurrImSet{idx}));
                    bar.Value = idx/(frames*2);
                end
                average_image = uint8(sum_images / frames);
    
                correlation_values = zeros(1, frames);
                for idx = 1:frames
                    correlation_values(idx) = corr2(average_image, rgb2gray(app.CurrImSet{idx}));
                    bar.Value = (frames+idx)/(frames*2);
                end
    
                [~, refIdx] = max(correlation_values);
                app.Text_RefIndex.Value = sprintf( '%d', refIdx );
                drawnow;
            else
                refIdx = str2double(app.RefImageTextArea_Reg.Value);

                if isnan(refIdx)
                    msgbox('Invalid reference index', '', 'warn');
                    app.Button_Register.Enable = "on";
                    return;
                else
                    refIdx = int16(refIdx);
                end
            end
            close(fig);

            % 读取两个 Bitmap 图像
            fixedImage = rgb2gray(app.CurrImSet{refIdx});

            % 定义配准参数
            tformType = app.Drop_RegType.Value; % 变换类型
            optimizer = registration.optimizer.OnePlusOneEvolutionary; % 优化器
            metric    = registration.metric.MattesMutualInformation;   % 相似性度量

            [fig,bar] = app.CreateWaitbar('Registration in progress...');
            for idx=1:frames
                movingImage = rgb2gray(app.CurrImSet{idx});

                % 使用参数进行图像配准
                tform = imregtform(movingImage, fixedImage, tformType, optimizer, metric);
                registeredImage = imwarp(movingImage, tform, 'OutputView', imref2d(size(fixedImage)), 'FillValues', 0); % 应用配准变换

                app.Im_Registered{idx} = cat(3, registeredImage, registeredImage, registeredImage);

                bar.Value = idx/frames;
            end
            close(fig);

            app.CurrImSet                   = app.Im_Registered;
            app.RegistrationButton.Enable   = "on";
            app.RegistrationButton.Value    = 1;
            app.FilteringButton.Value       = 0;
            app.CropButton.Value            = 0;
            app.OriginalButton.Value        = 0;
            app.RangeButton.Value           = 0;
            
            app.Slider.Value   = 1;
            app.Spinner.Value  = 1;
            app.Slider.Limits  = [1 frames];
            app.Spinner.Limits = [1 frames];

            app.DispIm(app.UIAxes, app.Im_Registered{1});
            axis(app.UIAxes, 'tight');
        end

        % Button pushed function: CalcButton_CBF
        function CalcCBF(app, event)
            if isempty(app.Region) || ~ishandle(app.Region)
                msgbox('No region selected', '', 'error');
                return;
            end

            [fig,bar] = app.CreateWaitbar("Calculating CBF ...");
            frames = numel(app.CurrImSet);
            CropImList = cell(frames, 1);
            for idx=1:frames
                [~, SelectedIm] = app.CropIm(app.CurrImSet{idx}, app.Region);
                CropImList{idx} = SelectedIm;
                bar.Value = idx / (frames*2);
            end

            reduction = app.TextArea_Reduction.Value / 100.0;
            framerate = app.EditFPS.Value;

            [~, signal_cillary] = CalcCbf( CropImList, reduction, framerate );
            coeff = round(frames/framerate);

            if true == app.SmoothCheckBox.Value
                signal_cillary = smooth(signal_cillary, 8, 'loess');
            end

            [mx1, mx2] = max(signal_cillary);
            mx2 = mx2/coeff;
            app.UIAxes_CBF.NextPlot = "replace";
            xAxis = (1:numel(signal_cillary)) / coeff;
            plot(app.UIAxes_CBF, xAxis, signal_cillary);
            app.UIAxes_CBF.NextPlot = 'add';
            plot( app.UIAxes_CBF, mx2, mx1, 'or' );
            text( app.UIAxes_CBF, mx2-2, mx1*1.03, sprintf('%.2f Hz', mx2) );
            app.TextArea_CBF.Value = sprintf('%.2f Hz', mx2);

            app.CBFPack.ImName          = app.ImName;
            app.CBFPack.ImIdx           = uint32(app.Spinner.Value);
            app.CBFPack.FPS             = framerate;
            app.CBFPack.Reduction       = reduction;
            app.CBFPack.Saved           = 0;
            app.CBFPack.Background      = 0;
            app.CBFPack.Result_Hz       = mx2;
            app.CBFPack.Cillary_Hz      = mx2;
            app.CBFPack.Cillary_Signal  = signal_cillary;
            app.CBFPack.Cillary_Region  = app.Region.Position;
            app.CBFPack.Cillary_Freq    = xAxis;

            if isa(app.Region, 'images.roi.Polygon')
                app.CBFPack.Cillary_IsPolygon = true;
            else
                app.CBFPack.Cillary_IsPolygon = false;
            end

            if isempty(app.RegionBack) || ~isvalid(app.RegionBack)
                close(fig);
                return;
            elseif isempty(app.RegionBack.Position)
                close(fig);
                return;
            end

            if true == app.FigureCheckBox.Value
                [mx1, mx2] = max(signal_cillary);
                mx2 = mx2/coeff;

                figure;
                plot(xAxis, signal_cillary, 'b');

                hold on;
                plot(mx2,mx1,'or');
                text(mx2-2,mx1*1.03,sprintf('%.2f Hz',mx2) );
                hold off;
            end

            CropImList = cell(frames, 1);
            for idx=1:frames
                [~, SelectedIm] = app.CropIm(app.CurrImSet{idx}, app.RegionBack);
                CropImList{idx} = SelectedIm;
                bar.Value = (idx+frames) / (frames*2);
            end
            
            [~, signal_background] = CalcCbf( CropImList, reduction, framerate );

            if true == app.SmoothCheckBox.Value
                signal_background = smooth(signal_background, 8, 'loess');
            end
            close(fig);

            % 将第二组信号的幅度缩放到与第一组相同
            new_min = min(abs(signal_cillary));
            new_max = max(abs(signal_cillary));
            
            % 计算原始数据的最小值和最大值
            original_min = min(abs(signal_background));
            original_max = max(abs(signal_background));
            
            % 计算缩放比例并缩放数据
            scale_factor  = (new_max - new_min) / (original_max - original_min);
            signal_scaled = scale_factor * (signal_background - original_min) + new_min;
            xAxis         = (1:numel(signal_scaled)) / coeff;

            if true == app.FigureCheckBox.Value
                [mx1, mx2] = max(signal_scaled);
                mx2 = mx2/coeff;

                figure;
                plot(xAxis, signal_scaled, 'b');

                hold on;
                plot(mx2,mx1,'or');
                text(mx2-2,mx1*1.03,sprintf('%.2f Hz',mx2) );
                hold off;
            end

            app.CBFPack.Background         = 1;
            app.CBFPack.Background_Hz      = mx2;
            app.CBFPack.Background_Signal  = signal_scaled;
            app.CBFPack.Background_Region  = app.RegionBack.Position;
            app.CBFPack.Background_Freq    = xAxis;

            if isa(app.RegionBack, 'images.roi.Polygon')
                app.CBFPack.Background_IsPolygon = true;
            else
                app.CBFPack.Background_IsPolygon = false;
            end

            difference = signal_cillary - signal_scaled;
            difference(difference<0) = 0;
            [mx1, mx2] = max(difference);
            mx2 = mx2/coeff;
            app.UIAxes_CBF.NextPlot = "replace";
            xAxis = (1:numel(difference)) / coeff;
            plot(app.UIAxes_CBF, xAxis, difference);
            app.UIAxes_CBF.NextPlot = 'add';
            plot( app.UIAxes_CBF, mx2, mx1, 'or' );
            text( app.UIAxes_CBF, mx2-2, mx1*1.03, sprintf('%.2f Hz',mx2) );
            app.TextArea_CBF.Value = sprintf('%.2f Hz',mx2);

            app.CBFPack.Result_Hz     = mx2;
            app.CBFPack.Result_Signal = difference;
            app.CBFPack.Result_Freq   = xAxis;
        end

        % Button pushed function: Button_Calc_MCT
        function CalcMCT(app, event)
            if isempty(app.Region) || ~ishandle(app.Region)
                msgbox('No region selected', '', 'error');
                return;
            end

            frames = numel(app.CurrImSet);
            CropImList = cell(frames, 1);
            for idx=1:frames
                
                [~, SelectedIm] = app.CropIm(app.CurrImSet{idx}, app.Region);
                CropImList{idx} = SelectedIm;
            end

            if isempty(CropImList)
                msgbox('Images not loaded', '', 'warn');
                return;
            end

            colShiftTotal = 0;
            rowShiftTotal = 0;
            colShiftRec = zeros(length(CropImList)-1, 1);
            rowShiftRec = zeros(length(CropImList)-1, 1);
            [fig, bar] = app.CreateWaitbar("Calculating MCT ...");
            for idx=2:length(CropImList)
                imPrev = rgb2gray(CropImList{idx-1});
                imNext = rgb2gray(CropImList{idx});
                [pixel, aline] = size(imPrev);
                [rowShift, colShift] = ImDftRegistration( imPrev, imNext, aline, pixel );
                colShiftTotal = colShiftTotal + colShift;
                rowShiftTotal = rowShiftTotal + rowShift;
                colShiftRec(idx-1) = colShiftTotal;
                rowShiftRec(idx-1) = rowShiftTotal;
                bar.Value = idx / length(CropImList);
            end
            close(fig);

            plot(app.UIAxes_MCT, colShiftRec, 'b', 'DisplayName', 'Horizontal');
            hold(app.UIAxes_MCT, 'on');
            plot(app.UIAxes_MCT, rowShiftRec, 'r', 'DisplayName', 'Vertial');
            legend(app.UIAxes_MCT, 'Location', 'best');
            hold(app.UIAxes_MCT, 'off');

            scale = app.EditMCTScale.Value;
            fps   = app.EditFPS.Value;

            avgValueHor = colShiftTotal / (length(CropImList)-1);
            px          = (app.HorDimEdit_mm.Value / app.HorDimEdit_px.Value) * 1000;
            ms          = (1 / fps) * 1000;
            resultHor   = avgValueHor * scale * px / ms;

            avgValueVet = rowShiftTotal / (length(CropImList)-1);
            px          = (app.VetDimEdit_mm.Value / app.VetDimEdit_px.Value) * 1000;
            ms          = (1 / fps) * 1000;
            resultVet   = avgValueVet * scale * px / ms;

            % Convert ms to min
            resultHor   = resultHor * 1000 * 60;
            resultVet   = resultVet * 1000 * 60;

            flowrate_px = sqrt(avgValueHor^2 + avgValueVet^2);
            flowrate_um = sqrt(resultHor^2 + resultVet^2);

            app.TextArea_MCT.Value    = sprintf('%.2f (H:%.2f V:%.2f)', flowrate_px, avgValueHor, avgValueVet);
            app.TextArea_Result.Value = sprintf('%.2f', flowrate_um );

            flowAngle = rad2deg(atan2(rowShiftTotal,-colShiftTotal));
            app.TextArea_Direction.Value = sprintf('%.2f', flowAngle);

            app.MCTPack.ImName      = app.ImName;
            app.MCTPack.ImIdx       = uint32(app.Spinner.Value);
            app.MCTPack.Saved       = 0;
            app.MCTPack.Result_px   = flowrate_px;
            app.MCTPack.Result_um   = flowrate_um;
            app.MCTPack.MCT_Region  = app.Region.Position;

            if isa(app.Region, 'images.roi.Polygon')
                app.MCTPack.IsPolygon = true;
            else
                app.MCTPack.IsPolygon = false;
            end
        end

        % Button pushed function: FigureButton
        function OpenFigureCBF(app, event)
            fig = figure;
            newAxes = axes(fig);
            copyobj(app.UIAxes_CBF.Children, newAxes);
        end

        % Button pushed function: Button_Fig_MCT
        function OpenFigureMCT(app, event)
            fig = figure;
            newAxes = axes(fig);
            copyobj(app.UIAxes_MCT.Children, newAxes);
        end

        % Selection changed function: RulerUnitButtonGroup
        function DimUnitChanged(app, event)
            selectedButton = app.RulerUnitButtonGroup.SelectedObject;
            if selectedButton == app.PixelButton
                app.DimFactorX = 1;
                app.DimFactorY = 1;
            else
                app.DimFactorX = (app.HorDimEdit_mm.Value / app.HorDimEdit_px.Value) * 1000;
                app.DimFactorY = (app.VetDimEdit_mm.Value / app.VetDimEdit_px.Value) * 1000;
            end

            app.UpdateTickLabels('X');
            app.UpdateTickLabels('Y');
        end

        % Button pushed function: DrawLayerButton
        function DrawLayer(app, event)
            if isempty(app.CurrImSet)
                warndlg('No images loaded');
                return;
            end
            if ~isempty(app.LayerLine)
                delete(app.LayerLine);
            end
            app.LayerLine = drawpolyline(app.UIAxes);
        end

        % Button pushed function: SegmentButton
        function SegmentImage(app, event)
            if isempty(app.LayerLine) || isempty(app.LayerLine.Position)
                return;
            end

            if 1 == app.ScaleCheckBox.Value
                scalingFactor = app.EditScale.Value;
                [fig,bar] = app.CreateWaitbar("Image resizing ...");
                for i=1:numel(app.CurrImSet)
                    app.CurrImSet{i} = imresize(app.CurrImSet{i}, ...
                        [size(app.CurrImSet{i}, 1), scalingFactor * size(app.CurrImSet{i}, 2)]);
                    bar.Value = i/numel(app.CurrImSet);
                end
                close(fig);
            end

            imsize = size(app.CurrImSet{1});
            pos = app.LayerLine.Position;
            if 1 == app.ScaleCheckBox.Value
                pos(:,1) = pos(:,1) * scalingFactor;
            end
            pos_ystart = pos(1,2);
            pos_yend   = pos(end,2);

            posStart = [1, pos_ystart];
            posEnd   = [imsize(2), pos_yend];
            layer    = [posStart; pos; posEnd];

            xq = 1:1:imsize(2);
            currLayer(:,1) = 1:imsize(2);
            currLayer(:,2) = interp1(layer(:,1), layer(:,2), xq, 'makima');

            if true == app.AuxlineCheckBox.Value
                auxLineDist = app.EditAuxLine.Value;
            else
                auxLineDist = 0;
            end

            segOffset = app.EditSegOffset.Value;

            [fig,bar] = app.CreateWaitbar("Segmentation in progress ...");
            app.Segment_Images = {};
            for i=1:numel(app.CurrImSet)
                im = rgb2gray(app.CurrImSet{i});
                [image, currLayer] = LayerSeg(im, currLayer, auxLineDist, segOffset);
                app.Segment_Images{i} = image;
                app.Segment_Layers{i} = currLayer;
                app.DispIm(app.UIAxes, image);
                axis(app.UIAxes, 'tight');
                app.Slider.Value = i;
                app.Spinner.Value = i;
                drawnow;

                bar.Value = i/numel(app.CurrImSet);
            end
            close(fig);

            smooth = false;
            if true == smooth
            
                % 预处理后的信号存储
                layers = app.Segment_Layers;
                PreprocessedSignals = cell(1, length(layers));
                
                for i = 1:length(layers)
                    signal = layers{i};
                    x = signal(:, 1);
                    y = signal(:, 2);
                    
                    % 找到唯一的x值及其索引
                    [xUnique, ~, ic] = unique(x);
                    
                    % 计算每个唯一x值的y值平均
                    yAverage = accumarray(ic, y, [], @mean);
                    
                    % 存储预处理后的信号，使用唯一的x值和对应的平均y值
                    PreprocessedSignals{i} = [xUnique, yAverage];
                end
                
                % 平滑参数，例如移动平均的窗口大小
                windowSize = 5;
                
                % 预分配平滑后的信号存储空间
                SmoothedSignals = cell(1, length(layers));
                
                for i = 1:length(layers)
                    signal = PreprocessedSignals{i}; % 提取第i个信号
                    x = signal(:, 1); % x坐标
                    y = signal(:, 2); % y坐标
                    
                    % 对y坐标进行平滑处理
                    smoothedY = smooth(y, windowSize);
                    
                    % 将平滑后的y坐标与原始x坐标组合，并存储回对应的cell中
                    SmoothedSignals{i} = [x, smoothedY];
                end
                
                for i = 1:length(layers)
                    layer = SmoothedSignals{i};
                    app.Segment_Images{i} = insertShape( app.CurrImSet{i}, 'line', [layer(:,1) layer(:,2)], ...
                               'Color', [255, 0, 0], 'LineWidth', 2, 'Opacity', 1);
                
                    newLayer(:,1) = layer(:,1);
                    newLayer(:,2) = min(max(layer(:,2)),layer(:,2)+6);
                
                    app.Segment_Images{i} = insertShape( app.Segment_Images{i}, 'line', [newLayer(:,1) newLayer(:,2)], ...
                               'Color', [0, 255, 0], 'LineWidth', 1, 'Opacity', 1);
                end
            end

            frameData = cell(numel(app.CurrImSet),3);
            frameData(:,1) = num2cell(1:numel(app.CurrImSet));
            frameData(:,2) = num2cell(0);
            frameData(:,3) = num2cell(0);
            app.FrameTable.Data = frameData;

            app.CurrImSet              = app.Segment_Images;
            app.Set1Button.Enable      = 'on';
            app.Set1Button.Value       = 1;
            app.OriginalButton.Value   = 0;
            app.CropButton.Value   = 0;
            app.FilteringButton.Value   = 0;
            app.RegistrationButton.Value = 0;
            app.Set2Button.Value       = 0;
        end

        % Button pushed function: FlattenButton
        function FlattenImage(app, event)
            if isempty(app.Segment_Layers)
                warndlg('No segmentation result');
                return;
            end

            [fig,bar] = app.CreateWaitbar("Flattening image(s) ...");
            for idx = 1:numel(app.Im_Original)
                img   = app.Im_Original{idx};
                layer = app.Segment_Layers{idx};
                if( isempty(layer) )
                    fprintf("No segmentation result for image %s\n", filename);
                else
                    x = layer(:,1);
                    y = layer(:,2);
                    [pixel,aline,~] = size(img);
                    newImg = zeros(pixel*2,aline, 'uint8');
                    for a = 1:aline
                        index = floor(mean(y((a)==x)));

                        newImg(pixel-index+1:pixel, a)   = img(1:index,a);
                        newImg(pixel+1:pixel*2-index, a) = img(index+1:end,a);
                    end

                    app.Segment_Flatten{idx} = newImg;
                    app.DispIm(app.UIAxes, newImg);
                    axis(app.UIAxes, 'tight');
                    drawnow;
                end
                bar.Value = idx/numel(app.Im_Original);
            end
            close(fig);

            app.CurrImSet              = app.Segment_Flatten;
            app.Set2Button.Enable      = "on";
            app.Set2Button.Value       = 1;
            app.OriginalButton.Value   = 0;
            app.CropButton.Value   = 0;
            app.FilteringButton.Value   = 0;
            app.RegistrationButton.Value = 0;
            app.Set1Button.Value       = 0;
        end

        % Button pushed function: SaveSegRes
        function SaveSegResult(app, event)
            if isempty(app.Segment_Layers)
                warndlg('No segmentation result');
                return;
            else
                [filename, pathname] = uiputfile('*.mat', 'Choose file');

                if isequal(filename,0) || isequal(pathname,0)
                    return; % 用户取消了保存操作
                else
                    fullpath = fullfile(pathname, filename);
                    layer = app.Segment_Layers;
                    save(fullpath, 'layer');
                end
            end
        end

        % Selection changed function: FrameTable
        function SelectFrame(app, event)
            if app.Set1Button.Value ~= 1
                return;
            end

            selection = app.FrameTable.Selection;
            disp(selection);
            if numel(selection) ~= 1
                return; % 多选情况
            end

            app.Spinner.Value = selection;
            app.Slider.Value  = selection;
            app.DispIm(app.UIAxes, app.CurrImSet{selection});

            x = app.Segment_Layers{selection}(:,1);
            y = app.Segment_Layers{selection}(:,2);
            ptData = cell(numel(x),3);
            ptData(:,1) = num2cell(1:numel(x));
            ptData(:,2) = num2cell(x);
            ptData(:,3) = num2cell(y);
            app.PointsTable.Data = ptData;

            % 降采样数据
            downsampledPoints = 20;
            interval = numel(x) / downsampledPoints;
            x = x(1:interval:end);
            y = y(1:interval:end);

            if ~isempty(app.LayerLine_Adjust)
                delete(app.LayerLine_Adjust);
            end

            app.LayerLine_Adjust = impoly(app.UIAxes, [x, y], 'Closed', false);
            app.LayerLine_Adjust.setColor('y');
        end

        % Button pushed function: RecordButton
        function RecordCBF(app, event)
            
            if isempty(app.CBFPack)
                warndlg('No calculation result!', 'Warning');
                return;
            end

            if isempty(app.MccLogDlg)
                app.MccLogDlg = MccLogger(app.MccCalculatorUIFigure.Position);
            end

            app.CBFPack = app.MccLogDlg.RecordCBF(app.CBFPack);
        end

        % Button pushed function: Button_Record_MCT
        function RecordMCT(app, event)
            if isempty(app.MCTPack)
                warndlg('No calculation result!', 'Warning');
                return;
            end

            if isempty(app.MccLogDlg)
                app.MccLogDlg = MccLogger(app.MccCalculatorUIFigure.Position);
            end

            app.MCTPack = app.MccLogDlg.RecordMCT(app.MCTPack);
        end

        % Close request function: MccCalculatorUIFigure
        function AppClosed(app, event)
            if ~isempty(app.MccLogDlg)
                delete(app.MccLogDlg);
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MccCalculatorUIFigure = uifigure('Visible', 'off');
            app.MccCalculatorUIFigure.Name = 'MCC Calculator';
            app.MccCalculatorUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.MccCalculatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @AppClosed, true);
            app.Layout_All = uigridlayout(app.MccCalculatorUIFigure);
            app.UIAxes = uiaxes(app.Layout_All);
            app.UIAxes.ZTick = [];
            app.Slider = uislider(app.Layout_All);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderChanging, true);
            app.Slider.Enable = 'off';
            app.Spinner = uispinner(app.Layout_All);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerChanged, true);
            app.Spinner.Enable = 'off';
            app.Spinner.Value = 1;
            app.PlayButton = uibutton(app.Layout_All, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayImages, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.LoadButton = uibutton(app.Layout_All, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImage, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.Layout_All, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImage, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.ImageList = uibuttongroup(app.Layout_All);
            app.ImageList.Title = 'Images';
            app.GridLayout_ImList = uigridlayout(app.ImageList);
            app.OriginalButton = uibutton(app.GridLayout_ImList, 'state');
            app.OriginalButton.ValueChangedFcn = createCallbackFcn(app, @ImSetChanged, true);
            app.OriginalButton.Enable = 'off';
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.CropButton = uibutton(app.GridLayout_ImList, 'state');
            app.CropButton.ValueChangedFcn = createCallbackFcn(app, @ImSetChanged, true);
            app.CropButton.Enable = 'off';
            app.CropButton.Text = 'Crop';
            app.FilteringButton = uibutton(app.GridLayout_ImList, 'state');
            app.FilteringButton.ValueChangedFcn = createCallbackFcn(app, @ImSetChanged, true);
            app.FilteringButton.Enable = 'off';
            app.FilteringButton.Text = 'Filtering';
            app.RegistrationButton = uibutton(app.GridLayout_ImList, 'state');
            app.RegistrationButton.ValueChangedFcn = createCallbackFcn(app, @ImSetChanged, true);
            app.RegistrationButton.Enable = 'off';
            app.RegistrationButton.Text = 'Registration';
            app.RangeButton = uibutton(app.GridLayout_ImList, 'state');
            app.RangeButton.ValueChangedFcn = createCallbackFcn(app, @ImSetChanged, true);
            app.RangeButton.Enable = 'off';
            app.RangeButton.Text = 'Range';
            app.TabGroup = uitabgroup(app.Layout_All);
            app.PreprocessTab = uitab(app.TabGroup);
            app.PreprocessTab.Title = 'Pre-process';
            app.GridLayout_Process = uigridlayout(app.PreprocessTab);
            app.ResultAxes_Process = uiaxes(app.GridLayout_Process);
            app.ResultAxes_Process.ZTick = [];
            app.RegionAxes_Process = uiaxes(app.GridLayout_Process);
            app.RegionAxes_Process.XTick = [];
            app.RegionAxes_Process.YTick = [];
            app.RegionDrop_Process = uidropdown(app.GridLayout_Process);
            app.RegionDrop_Process.Items = {'        Rectangle', '           Polygon'};
            app.RegionDrop_Process.Value = '        Rectangle';
            app.Button_Select_Process = uibutton(app.GridLayout_Process, 'push');
            app.Button_Select_Process.ButtonPushedFcn = createCallbackFcn(app, @SelectRegion, true);
            app.Button_Select_Process.Tag = 'Process';
            app.Button_Select_Process.Text = 'Select';
            app.GridLayout_ProcessBtn = uigridlayout(app.GridLayout_Process);
            app.Button_Crop = uibutton(app.GridLayout_ProcessBtn, 'push');
            app.Button_Crop.ButtonPushedFcn = createCallbackFcn(app, @CropImages, true);
            app.Button_Crop.Text = 'Crop';
            app.Button_Filter = uibutton(app.GridLayout_ProcessBtn, 'push');
            app.Button_Filter.ButtonPushedFcn = createCallbackFcn(app, @FilterImages, true);
            app.Button_Filter.Text = 'Filter';
            app.Button_Register = uibutton(app.GridLayout_ProcessBtn, 'push');
            app.Button_Register.ButtonPushedFcn = createCallbackFcn(app, @RegisterImages, true);
            app.Button_Register.Text = 'Register';
            app.GridLayout_ProcessInfo = uigridlayout(app.GridLayout_Process);
            app.IndexLabel_3 = uilabel(app.GridLayout_ProcessInfo);
            app.IndexLabel_3.Text = 'Index :';
            app.Text_RefIndex = uitextarea(app.GridLayout_ProcessInfo);
            app.Text_RefIndex.Value = {'-'};
            app.IndexLabel_2 = uilabel(app.GridLayout_ProcessInfo);
            app.IndexLabel_2.Text = 'Thres :';
            app.Text_Thres = uitextarea(app.GridLayout_ProcessInfo);
            app.Text_Thres.Value = {'-'};
            app.Check_AutoThres = uicheckbox(app.GridLayout_ProcessInfo);
            app.Check_AutoThres.Text = 'Auto';
            app.Check_AutoThres.Value = true;
            app.Check_AutoIndex = uicheckbox(app.GridLayout_ProcessInfo);
            app.Check_AutoIndex.Text = 'Auto';
            app.Check_AutoIndex.Value = true;
            app.TypeLabel = uilabel(app.GridLayout_ProcessInfo);
            app.TypeLabel.Text = 'Reg Type :';
            app.Drop_RegType = uidropdown(app.GridLayout_ProcessInfo);
            app.Drop_RegType.Items = {'Affine', 'Similarity', 'Projective'};
            app.Drop_RegType.Value = 'Similarity';
            app.RangePanel = uipanel(app.GridLayout_Process);
            app.RangePanel.Title = 'Range';
            app.GridLayout_Range = uigridlayout(app.RangePanel);
            app.StartFromEditField_2Label = uilabel(app.GridLayout_Range);
            app.StartFromEditField_2Label.Text = 'Start From:';
            app.EditStartFrom = uieditfield(app.GridLayout_Range, 'numeric');
            app.EditStartFrom.Limits = [0 Inf];
            app.EditStartFrom.ValueDisplayFormat = '%.0f';
            app.StopAtEditField_2Label = uilabel(app.GridLayout_Range);
            app.StopAtEditField_2Label.Text = 'Stop At:';
            app.EditStopAt = uieditfield(app.GridLayout_Range, 'numeric');
            app.EditStopAt.Limits = [0 Inf];
            app.EditStopAt.ValueDisplayFormat = '%.0f';
            app.SetButton = uibutton(app.GridLayout_Range, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetImRange, true);
            app.SetButton.Text = 'Set';
            app.CBFTab = uitab(app.TabGroup);
            app.CBFTab.Title = 'CBF';
            app.GridLayout_CBF = uigridlayout(app.CBFTab);
            app.UIAxes_CBF = uiaxes(app.GridLayout_CBF);
            app.GridLayout_CBF_Buttons = uigridlayout(app.GridLayout_CBF);
            app.FigureButton = uibutton(app.GridLayout_CBF_Buttons, 'push');
            app.FigureButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFigureCBF, true);
            app.FigureButton.Text = 'Figure';
            app.RecordButton = uibutton(app.GridLayout_CBF_Buttons, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordCBF, true);
            app.RecordButton.Text = 'Record';
            app.CalcButton_CBF = uibutton(app.GridLayout_CBF_Buttons, 'push');
            app.CalcButton_CBF.ButtonPushedFcn = createCallbackFcn(app, @CalcCBF, true);
            app.CalcButton_CBF.Text = 'Calculate';
            app.SmoothCheckBox = uicheckbox(app.GridLayout_CBF_Buttons);
            app.SmoothCheckBox.Text = '  Smooth';
            app.SmoothCheckBox.Value = true;
            app.RecutionLabel = uilabel(app.GridLayout_CBF_Buttons);
            app.RecutionLabel.Text = 'Recution:';
            app.TextArea_Reduction = uieditfield(app.GridLayout_CBF_Buttons, 'numeric');
            app.TextArea_Reduction.Limits = [0 99];
            app.TextArea_Reduction.ValueDisplayFormat = '%.0f';
            app.TextArea_Reduction.Value = 5;
            app.FigureCheckBox = uicheckbox(app.GridLayout_CBF_Buttons);
            app.FigureCheckBox.Text = '  Figure';
            app.FigureCheckBox.Value = true;
            app.Layout_Cillary = uigridlayout(app.GridLayout_CBF);
            app.RegionAxes_Cillary = uiaxes(app.Layout_Cillary);
            app.RegionAxes_Cillary.XTick = [];
            app.RegionAxes_Cillary.YTick = [];
            app.Button_Region_Cillary = uibutton(app.Layout_Cillary, 'push');
            app.Button_Region_Cillary.ButtonPushedFcn = createCallbackFcn(app, @SelectRegion, true);
            app.Button_Region_Cillary.Tag = 'Cillary';
            app.Button_Region_Cillary.Text = 'Cillary';
            app.RegionDrop_Cillary = uidropdown(app.Layout_Cillary);
            app.RegionDrop_Cillary.Items = {'        Rectangle', '           Polygon'};
            app.RegionDrop_Cillary.Value = '        Rectangle';
            app.Layout_Background = uigridlayout(app.GridLayout_CBF);
            app.RegionAxes_Background = uiaxes(app.Layout_Background);
            app.RegionAxes_Background.XTick = [];
            app.RegionAxes_Background.YTick = [];
            app.Button_Region_Background = uibutton(app.Layout_Background, 'push');
            app.Button_Region_Background.ButtonPushedFcn = createCallbackFcn(app, @SelectRegion, true);
            app.Button_Region_Background.Tag = 'Background';
            app.Button_Region_Background.Text = 'Background';
            app.RegionDrop_Background = uidropdown(app.Layout_Background);
            app.RegionDrop_Background.Items = {'        Rectangle', '           Polygon'};
            app.RegionDrop_Background.Value = '        Rectangle';
            app.TextArea_CBF = uitextarea(app.GridLayout_CBF);
            app.TextArea_CBF.Value = {'-'};
            app.MCTTab = uitab(app.TabGroup);
            app.MCTTab.Title = 'MCT';
            app.GridLayout_MCT = uigridlayout(app.MCTTab);
            app.RegionAxes_MCT = uiaxes(app.GridLayout_MCT);
            app.RegionAxes_MCT.XTick = [];
            app.RegionAxes_MCT.YTick = [];
            app.UIAxes_MCT = uiaxes(app.GridLayout_MCT);
            app.Button_Select_MCT = uibutton(app.GridLayout_MCT, 'push');
            app.Button_Select_MCT.ButtonPushedFcn = createCallbackFcn(app, @SelectRegion, true);
            app.Button_Select_MCT.Tag = 'MCT';
            app.Button_Select_MCT.Text = 'Select';
            app.RegionDrop_MCT = uidropdown(app.GridLayout_MCT);
            app.RegionDrop_MCT.Items = {'        Rectangle', '           Polygon'};
            app.RegionDrop_MCT.Value = '        Rectangle';
            app.GridLayout_MCTInfo = uigridlayout(app.GridLayout_MCT);
            app.ResultLabel_2 = uilabel(app.GridLayout_MCTInfo);
            app.ResultLabel_2.Text = 'Direction (°) :';
            app.TextArea_Direction = uitextarea(app.GridLayout_MCTInfo);
            app.TextArea_Direction.Editable = 'off';
            app.TextArea_Direction.Value = {'-'};
            app.ResultLabel = uilabel(app.GridLayout_MCTInfo);
            app.ResultLabel.Text = 'Result (um/min) :';
            app.TextArea_Result = uitextarea(app.GridLayout_MCTInfo);
            app.TextArea_Result.Editable = 'off';
            app.TextArea_Result.Value = {'-'};
            app.ScaleLabel = uilabel(app.GridLayout_MCTInfo);
            app.ScaleLabel.Text = 'Scale :';
            app.EditMCTScale = uieditfield(app.GridLayout_MCTInfo, 'numeric');
            app.EditMCTScale.ValueDisplayFormat = '%.1f';
            app.EditMCTScale.Value = 1;
            app.FlowrateLabel = uilabel(app.GridLayout_MCTInfo);
            app.FlowrateLabel.Text = 'Flow rate (px/fm) :';
            app.TextArea_MCT = uitextarea(app.GridLayout_MCTInfo);
            app.TextArea_MCT.Editable = 'off';
            app.TextArea_MCT.Value = {'-'};
            app.GridLayout_Buttons_MCT = uigridlayout(app.GridLayout_MCT);
            app.Button_Calc_MCT = uibutton(app.GridLayout_Buttons_MCT, 'push');
            app.Button_Calc_MCT.ButtonPushedFcn = createCallbackFcn(app, @CalcMCT, true);
            app.Button_Calc_MCT.Text = 'Calculate';
            app.Button_Fig_MCT = uibutton(app.GridLayout_Buttons_MCT, 'push');
            app.Button_Fig_MCT.ButtonPushedFcn = createCallbackFcn(app, @OpenFigureMCT, true);
            app.Button_Fig_MCT.Text = 'Figure';
            app.Button_Record_MCT = uibutton(app.GridLayout_Buttons_MCT, 'push');
            app.Button_Record_MCT.ButtonPushedFcn = createCallbackFcn(app, @RecordMCT, true);
            app.Button_Record_MCT.Text = 'Record';
            app.SegmentTab = uitab(app.TabGroup);
            app.SegmentTab.Title = 'Segment';
            app.GridLayout_Flatten = uigridlayout(app.SegmentTab);
            app.DrawLayerButton = uibutton(app.GridLayout_Flatten, 'push');
            app.DrawLayerButton.ButtonPushedFcn = createCallbackFcn(app, @DrawLayer, true);
            app.DrawLayerButton.Text = 'Draw Layer';
            app.SegmentButton = uibutton(app.GridLayout_Flatten, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentImage, true);
            app.SegmentButton.Text = 'Segment';
            app.FlattenButton = uibutton(app.GridLayout_Flatten, 'push');
            app.FlattenButton.ButtonPushedFcn = createCallbackFcn(app, @FlattenImage, true);
            app.FlattenButton.Text = 'Flatten';
            app.ImageList_Seg = uipanel(app.GridLayout_Flatten);
            app.ImageList_Seg.Title = 'Images';
            app.GridLayout_ImList2 = uigridlayout(app.ImageList_Seg);
            app.Set2Button = uibutton(app.GridLayout_ImList2, 'state');
            app.Set2Button.ValueChangedFcn = createCallbackFcn(app, @ImSetChanged, true);
            app.Set2Button.Enable = 'off';
            app.Set2Button.Text = 'Set 2';
            app.Set1Button = uibutton(app.GridLayout_ImList2, 'state');
            app.Set1Button.ValueChangedFcn = createCallbackFcn(app, @ImSetChanged, true);
            app.Set1Button.Enable = 'off';
            app.Set1Button.Text = 'Set 1';
            app.SaveSegRes = uibutton(app.GridLayout_Flatten, 'push');
            app.SaveSegRes.ButtonPushedFcn = createCallbackFcn(app, @SaveSegResult, true);
            app.SaveSegRes.Text = 'Save';
            app.ScaleCheckBox = uicheckbox(app.GridLayout_Flatten);
            app.ScaleCheckBox.Text = 'Scale';
            app.EditScale = uieditfield(app.GridLayout_Flatten, 'numeric');
            app.EditScale.ValueDisplayFormat = '%.2f';
            app.EditScale.Value = 2;
            app.TabGroup_SegResult = uitabgroup(app.GridLayout_Flatten);
            app.FramesTab = uitab(app.TabGroup_SegResult);
            app.FramesTab.Title = 'Frames';
            app.FrameTable = uitable(app.FramesTab);
            app.FrameTable.ColumnName = {'Frame'; 'Manual'; 'Adjust'};
            app.FrameTable.RowName = {};
            app.FrameTable.SelectionType = 'row';
            app.FrameTable.ColumnEditable = [false false true];
            app.FrameTable.SelectionChangedFcn = createCallbackFcn(app, @SelectFrame, true);
            app.PointsTab = uitab(app.TabGroup_SegResult);
            app.PointsTab.Title = 'Points';
            app.PointsTable = uitable(app.PointsTab);
            app.PointsTable.ColumnName = {'No.'; 'X'; 'Y'};
            app.PointsTable.RowName = {};
            app.PointsTable.SelectionType = 'row';
            app.AuxlineCheckBox = uicheckbox(app.GridLayout_Flatten);
            app.AuxlineCheckBox.Text = 'Aux line';
            app.AuxlineCheckBox.Value = true;
            app.EditAuxLine = uieditfield(app.GridLayout_Flatten, 'numeric');
            app.EditAuxLine.ValueDisplayFormat = '%.0f';
            app.EditAuxLine.Value = 6;
            app.ScaleLabel_2 = uilabel(app.GridLayout_Flatten);
            app.ScaleLabel_2.Text = 'Offset';
            app.EditSegOffset = uieditfield(app.GridLayout_Flatten, 'numeric');
            app.EditSegOffset.Limits = [0 Inf];
            app.EditSegOffset.ValueDisplayFormat = '%.0f';
            app.EditSegOffset.Value = 10;
            app.ASLTab = uitab(app.TabGroup);
            app.ASLTab.Title = 'ASL';
            app.MucusBMTab = uitab(app.TabGroup);
            app.MucusBMTab.Title = 'Mucus/BM';
            app.EpithelialTab = uitab(app.TabGroup);
            app.EpithelialTab.Title = 'Epithelial';
            app.CBPTab = uitab(app.TabGroup);
            app.CBPTab.Title = 'CBP';
            app.PCLTab = uitab(app.TabGroup);
            app.PCLTab.Title = 'PCL';
            app.CoverageTab = uitab(app.TabGroup);
            app.CoverageTab.Title = 'Coverage';
            app.CPBTab = uitab(app.TabGroup);
            app.CPBTab.Title = 'CPB';
            app.PTLTab = uitab(app.TabGroup);
            app.PTLTab.Title = 'PTL';
            app.SettingsPanel = uipanel(app.Layout_All);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout_Setting = uigridlayout(app.SettingsPanel);
            app.FPSLabel = uilabel(app.GridLayout_Setting);
            app.FPSLabel.Text = 'FPS :';
            app.EditFPS = uieditfield(app.GridLayout_Setting, 'numeric');
            app.EditFPS.Limits = [0 Inf];
            app.EditFPS.ValueDisplayFormat = '%.0f';
            app.EditFPS.Value = 50;
            app.RulerUnitButtonGroup = uibuttongroup(app.GridLayout_Setting);
            app.RulerUnitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DimUnitChanged, true);
            app.RulerUnitButtonGroup.Title = 'Ruler Unit';
            app.PixelButton = uiradiobutton(app.RulerUnitButtonGroup);
            app.PixelButton.Text = 'Pixel';
            app.PixelButton.Value = true;
            app.MicrometerumButton = uiradiobutton(app.RulerUnitButtonGroup);
            app.MicrometerumButton.Text = 'Micrometer (um)';
            app.mmLabel_2 = uilabel(app.GridLayout_Setting);
            app.mmLabel_2.Text = '(mm)';
            app.HorizontalDimensionEditFieldLabel = uilabel(app.GridLayout_Setting);
            app.HorizontalDimensionEditFieldLabel.Text = 'Horizontal Dimension';
            app.mmLabel = uilabel(app.GridLayout_Setting);
            app.mmLabel.Text = '(mm)';
            app.pxLabel_2 = uilabel(app.GridLayout_Setting);
            app.pxLabel_2.Text = '(px)';
            app.pxLabel = uilabel(app.GridLayout_Setting);
            app.pxLabel.Text = '(px)';
            app.HorDimEdit_mm = uieditfield(app.GridLayout_Setting, 'numeric');
            app.HorDimEdit_mm.Limits = [0 Inf];
            app.HorDimEdit_mm.ValueDisplayFormat = '%.3f';
            app.HorDimEdit_mm.Value = 1.4054;
            app.HorDimEdit_px = uieditfield(app.GridLayout_Setting, 'numeric');
            app.HorDimEdit_px.Limits = [0 Inf];
            app.HorDimEdit_px.ValueDisplayFormat = '%.0f';
            app.HorDimEdit_px.Value = 400;
            app.VerticalDimensionLabel = uilabel(app.GridLayout_Setting);
            app.VerticalDimensionLabel.Text = 'Vertical Dimension';
            app.VetDimEdit_mm = uieditfield(app.GridLayout_Setting, 'numeric');
            app.VetDimEdit_mm.Limits = [0 Inf];
            app.VetDimEdit_mm.ValueDisplayFormat = '%.3f';
            app.VetDimEdit_mm.Value = 0.7108;
            app.VetDimEdit_px = uieditfield(app.GridLayout_Setting, 'numeric');
            app.VetDimEdit_px.Limits = [0 Inf];
            app.VetDimEdit_px.ValueDisplayFormat = '%.0f';
            app.VetDimEdit_px.Value = 800;
            % Show the figure after all components are created
            app.MccCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MccCalculator

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MccCalculatorUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.MccCalculatorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MccCalculatorUIFigure)
        end
    end
end