classdef MccLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MccLoggerUIFigure  matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        SaveButton         matlab.ui.control.Button
        Message            matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        PackList_CBF = {};
        PackList_MCT = {};
    end
    
    methods (Access = public)
        function CBFPack = RecordCBF(app, CBFPack)
            oldText = app.Message.Value;

            if CBFPack.Saved == 1
                oldText{end+1, 1} = '';
                oldText{end+1, 1} = 'Result already saved!';
                app.Message.Value = oldText;
                return;
            end

            oldText{end+1, 1} = '--------------------------------------------------';
            title = [char(string(datetime('now', 'Format', 'HH:mm:ss'))) ' - ' CBFPack.ImName ' - ' num2str(CBFPack.ImIdx, '%u')];
            oldText{end+1, 1} = char(title);
            oldText{end+1, 1} = '';

            oldText{end+1, 1} = char(sprintf('Cillary: %.2f Hz', CBFPack.Cillary_Hz));
            oldText{end+1, 1} = RegionToString(app, CBFPack.Cillary_Region, CBFPack.Cillary_IsPolygon);

            if 1 == CBFPack.Background
                oldText{end+1, 1} = '';
                oldText{end+1, 1} = char(sprintf('Background: %.2f Hz', CBFPack.Background_Hz));
                oldText{end+1, 1} = RegionToString(app, CBFPack.Background_Region, CBFPack.Background_IsPolygon);

                oldText{end+1, 1} = '';
                result = sprintf('Result: %.2f Hz', CBFPack.Result_Hz);
                oldText{end+1, 1} = char(result);
            else
                oldText{end+1, 1} = '';
                oldText{end+1, 1} = 'Background: -';
            end

            app.Message.Value = oldText;
            CBFPack.Saved = 1;

            app.PackList_CBF{end+1} = CBFPack;
        end

        function MCTPack = RecordMCT(app, MCTPack)
            oldText = app.Message.Value;

            if MCTPack.Saved == 1
                oldText{end+1, 1} = '';
                oldText{end+1, 1} = 'Result already saved!';
                app.Message.Value = oldText;
                return;
            end

            oldText{end+1, 1} = '--------------------------------------------------';
            title = [char(string(datetime('now', 'Format', 'HH:mm:ss'))) ' - ' MCTPack.ImName ' - ' num2str(MCTPack.ImIdx, '%u')];
            oldText{end+1, 1} = char(title);
            oldText{end+1, 1} = '';

            oldText{end+1, 1} = char(sprintf('MCT (px/fm): %.2f', MCTPack.Result_px));
            oldText{end+1, 1} = char(sprintf('MCT (um/min): %.2f', MCTPack.Result_um));
            
            posStr = RegionToString(app, MCTPack.MCT_Region, MCTPack.IsPolygon);
            oldText{end+1, 1} = char(posStr);

            app.Message.Value = oldText;
            MCTPack.Saved = 1;

            app.PackList_MCT{end+1} = MCTPack;
        end

        function regionDes = RegionToString(~, Region, IsPolygon)
            if true == IsPolygon
                regionDes = 'Polygon - ';
                for i=1:size(Region, 1)
                    regionDes = [regionDes '(' num2str(Region(i,1), '%.1f') ', ' num2str(Region(i,2), '%.1f') ') '];
                end
            else
                regionDes = ['Rectangle - [' num2str(Region(1), '%.1f') '  ' num2str(Region(2), '%.1f') '  ' num2str(Region(3), '%.1f') '  ' num2str(Region(4), '%.1f') ']'];
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Position)
            oldPos = app.MccLoggerUIFigure.Position;
            app.MccLoggerUIFigure.Position = [Position(1) + Position(3) + 5, Position(2), oldPos(3), Position(4)];
        end

        % Button pushed function: SaveButton
        function SaveResult(app, event)
            [filename, pathname] = uiputfile('*.xlsx', 'Save Data to Excel', 'cbf.xlsx');
            
            if isequal(filename,0) || isequal(pathname,0)
                disp('用户取消了保存操作');
            else
                fullpath = fullfile(pathname, filename);
                for i=1:numel(app.PackList_CBF)
                    pack = app.PackList_CBF{i};
                    excelData = cell(18, length(pack.Cillary_Signal)+1);

                    excelData(1,1)  = num2cell("Image set: ");
                    excelData(2,1)  = num2cell("Image index: ");
                    excelData(3,1)  = num2cell("FPS: ");
                    excelData(4,1)  = num2cell("Reduction: ");

                    excelData(6,1)  = num2cell("Cillary result: ");
                    excelData(7,1)  = num2cell("Cillary region: ");
                    excelData(8,1)  = num2cell("Cillary signal: ");
                    excelData(9,1)  = num2cell("Cillary frequency: ");

                    excelData(11,1) = num2cell("Background result: ");
                    excelData(12,1) = num2cell("Background region: ");
                    excelData(13,1) = num2cell("Background signal: ");
                    excelData(14,1) = num2cell("Background frequency: ");


                    excelData(16,1) = num2cell("Final result: ");
                    excelData(17,1) = num2cell("Final signal: ");
                    excelData(18,1) = num2cell("Final frequency: ");

                    excelData(1,2)     = num2cell(string(pack.ImName));
                    excelData(2,2)     = num2cell(pack.ImIdx);
                    excelData(3,2)     = num2cell(pack.FPS);
                    excelData(4,2)     = num2cell(pack.Reduction);

                    excelData(6,2)     = num2cell(pack.Cillary_Hz);
                    excelData(7,2)     = cellstr(RegionToString(app, pack.Cillary_Region, pack.Cillary_IsPolygon));
                    excelData(8,2:end) = num2cell(pack.Cillary_Signal);
                    excelData(9,2:end) = num2cell(pack.Cillary_Freq);

                    if 1 == pack.Background
                        excelData(11,2)     = num2cell(pack.Background_Hz);
                        excelData(12,2)     = cellstr(RegionToString(app, pack.Background_Region, pack.Background_IsPolygon));
                        excelData(13,2:end) = num2cell(pack.Background_Signal);
                        excelData(14,2:end) = num2cell(pack.Background_Freq);

                        excelData(16,2)     = num2cell(pack.Result_Hz);
                        excelData(17,2:end) = num2cell(pack.Result_Signal);
                        excelData(18,2:end) = num2cell(pack.Result_Freq);
                    else
                        excelData(11,2) = num2cell("-");
                        excelData(12,2) = num2cell("-");
                        excelData(13,2) = num2cell("-");
                        excelData(14,2) = num2cell("-");

                        excelData(16,2) = num2cell("-");
                        excelData(17,2) = num2cell("-");
                        excelData(18,2) = num2cell("-");
                    end

                    writecell(excelData, fullpath, 'Sheet', i);
                end

                for i=1:numel(app.PackList_MCT)
                    
                    pack = app.PackList_MCT{i};

                    excelData       = cell(7,2);
                    excelData(1,1)  = num2cell("Image set: ");
                    excelData(2,1)  = num2cell("Image index: ");
                    excelData(3,1)  = num2cell("MCT (px/fm): ");
                    excelData(4,1)  = num2cell("MCT (um/min): ");
                    excelData(5,1)  = num2cell("MCT region: ");

                    excelData(1,2)  = num2cell(string(pack.ImName));
                    excelData(2,2)  = num2cell(pack.ImIdx);
                    excelData(3,2)  = num2cell(pack.Result_px);
                    excelData(4,2)  = num2cell(pack.Result_um);
                    excelData(5,2)  = cellstr(RegionToString(app, pack.MCT_Region, pack.IsPolygon));

                    writecell(excelData, fullpath, 'Sheet', i+numel(app.PackList_CBF));
                end

                msgbox('File saved successfully', 'Info');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MccLoggerUIFigure = uifigure('Visible', 'off');
            app.MccLoggerUIFigure.Name = 'CBF Logger';
            app.GridLayout = uigridlayout(app.MccLoggerUIFigure);
            app.Message = uitextarea(app.GridLayout);
            app.Message.Editable = 'off';
            app.Message.Value = {'Logger start'};
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResult, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.MccLoggerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MccLogger(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MccLoggerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MccLoggerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MccLoggerUIFigure)
        end
    end
end