classdef ASLCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ASLCalculatorUIFigure         matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Tree                          matlab.ui.container.Tree
        GridLayout_Information        matlab.ui.container.GridLayout
        IntensityRatioEditField       matlab.ui.control.NumericEditField
        IntensityRatioEditFieldLabel  matlab.ui.control.Label
        IntensityEditField            matlab.ui.control.NumericEditField
        IntensityEditFieldLabel       matlab.ui.control.Label
        NameLabel                     matlab.ui.control.Label
        umLabel_2                     matlab.ui.control.Label
        FinalASLEditField             matlab.ui.control.NumericEditField
        FinalASLEditFieldLabel        matlab.ui.control.Label
        GridLayout_MarkZone           matlab.ui.container.GridLayout
        CalculateButton               matlab.ui.control.Button
        ListBox                       matlab.ui.control.ListBox
        ClearButton                   matlab.ui.control.Button
        AddMarkButton                 matlab.ui.control.Button
        umLabel                       matlab.ui.control.Label
        ReferenceASLEditField         matlab.ui.control.NumericEditField
        ReferenceASLEditFieldLabel    matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        WidthEditFieldLabel           matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        FrameEditField                matlab.ui.control.NumericEditField
        FrameLabel                    matlab.ui.control.Label
        InformationLabel              matlab.ui.control.Label
        ImageViewLabel                matlab.ui.control.Label
        ImageSetsLabel                matlab.ui.control.Label
        setsLabel                     matlab.ui.control.Label
        Spinner                       matlab.ui.control.Spinner
        Slider                        matlab.ui.control.Slider
        TabGroup                      matlab.ui.container.TabGroup
        MainTab                       matlab.ui.container.Tab
        GridLayout_Tab                matlab.ui.container.GridLayout
        Panel_Seperator5              matlab.ui.container.Panel
        Panel_Seperator4              matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        StandardDeviationCheckBox     matlab.ui.control.CheckBox
        MeanCheckBox                  matlab.ui.control.CheckBox
        WindowEditField               matlab.ui.control.NumericEditField
        ReportButton                  matlab.ui.control.Button
        VerticalResolutionPanel       matlab.ui.container.Panel
        GridLayout_VetRes             matlab.ui.container.GridLayout
        mmLabel                       matlab.ui.control.Label
        VetDimEdit_mm                 matlab.ui.control.NumericEditField
        VetDimEdit_px                 matlab.ui.control.NumericEditField
        pxLabel                       matlab.ui.control.Label
        Panel_Seperator1              matlab.ui.container.Panel
        SaveNextButton                matlab.ui.control.Button
        SegmentButton                 matlab.ui.control.Button
        ButtonGroup                   matlab.ui.container.ButtonGroup
        BasementButton                matlab.ui.control.RadioButton
        SurfaceButton                 matlab.ui.control.RadioButton
        ProcessButton                 matlab.ui.control.Button
        Panel_Seperator3              matlab.ui.container.Panel
        RulerUnitButtonGroup          matlab.ui.container.ButtonGroup
        MicrometerusButton            matlab.ui.control.RadioButton
        PixelButton                   matlab.ui.control.RadioButton
        Panel_Seperator2              matlab.ui.container.Panel
        PlayButton                    matlab.ui.control.StateButton
        ExportButton                  matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        ContextDelete                 matlab.ui.container.ContextMenu
        DeleteMenu                    matlab.ui.container.Menu
        ExpandMenu                    matlab.ui.container.Menu
        CollapseMenu                  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        folderStruct;
        Playing = 0;
        Image_Current = {};
        Rectangles;
        Layer_Top;
        Layer_Bottom;
    end
    
    methods (Access = private)
        
        function [fig, wb] = CreateWaitbar(app, title)
            waitbarWidth  = 400;
            waitbarHeight = 80;

            left  = app.ASLCalculatorUIFigure.Position(1)+app.ASLCalculatorUIFigure.Position(3)/2-waitbarWidth/2;
            right = app.ASLCalculatorUIFigure.Position(2)+app.ASLCalculatorUIFigure.Position(4)/2-waitbarHeight/2;
            fig   = uifigure('NumberTitle', 'off', 'MenuBar', 'none', 'ToolBar', 'none', 'Resize', 'off', 'Name', title, ...
                           'Position', [left, right, waitbarWidth, waitbarHeight], 'WindowStyle','modal');

            wb = uiprogressdlg(fig);
            wb.Message = 'In progress ...';
        end
        
        function DisplayImage(~, axes, Image)
            dimension = ndims(Image);
            if dimension == 2
                Image = cat(3, Image, Image, Image);
                imagesc(axes, Image);
            elseif dimension == 3
                imagesc(axes, Image);
            elseif dimension == 4
                warndlg('Invalid image format');
            else
                warndlg('Invalid image format');
            end

            axis(axes, 'tight');
        end
        
        function PlayingImages(app)
            imIdx  = uint16(app.Spinner.Value);
            frames = numel(app.Image_Current);

            if imIdx == frames
                imIdx = 1;
            end

            for i=imIdx:frames
                if app.Playing == 0
                    break;
                end
                
                % 读取当前 UIAxes 的缩放状态
                xlimOld = app.UIAxes.XLim;
                ylimOld = app.UIAxes.YLim;

                app.DisplayImage(app.UIAxes, app.Image_Current{i});
                app.Slider.Value  = double(i);
                app.Spinner.Value = double(i);

                % 恢复之前的缩放状态
                app.UIAxes.XLim = xlimOld;
                app.UIAxes.YLim = ylimOld;
                drawnow;
            end

            app.PlayButton.Text  = "Play";
            app.PlayButton.Icon  = "slrtRunIcon.png";
            app.PlayButton.Value = 0;
            app.Playing          = 0;
        end
        
        function LoadImage(app, InputName, ImageType)
            % 找到匹配项的索引
            idx = strcmp({app.folderStruct.folderName}, InputName);
            imageSet = app.folderStruct(idx); % 获取匹配的 struct

            app.Image_Current = {};
            frames = length(imageSet.imageFiles);
            [fig,bar] = app.CreateWaitbar("Loading image(s)...");

            heightRef = 0;
            widthRef  = 0;
            for i=1:frames
                switch ImageType
                    case 'Original'
                        img = imread( imageSet.imageFiles{i} );
                    case 'Segment'
                        img = imageSet.image_segment{i};
                    case 'ASL'
                        img = imageSet.image_asl{i};
                    case 'Equalized'
                        img = imageSet.image_equalized;
                    case 'Average'
                        img = imageSet.image_average(:,:,i);
                    case 'SD'
                        img = imageSet.image_sd(:,:,i);
                end
                
                [height, width, channel] = size(img);

                if heightRef~= 0
                    if heightRef ~= height
                        warndlg('Invalid image size');
                        if isvalid(fig)
                            close(fig);
                        end
                        return;
                    end
                else
                    heightRef = height;
                end

                if widthRef~= 0
                    if widthRef ~= width
                        warndlg('Invalid image size');
                        return;
                    end
                else
                    widthRef = width;
                end

                if channel == 1
                    app.Image_Current{i} = cat(3, img, img, img);
                else
                    app.Image_Current{i} = img;
                end

                bar.Value = i/frames;
            end

            if isvalid(fig)
                close(fig);
            end

            app.ListBox.Items = {};
            for i=1:numel(imageSet.marks)
                markName = sprintf('Mark%d: [%d, %d]', ...
                    i, round(imageSet.marks{i}(1)), ...
                    round(imageSet.marks{i}(1) + imageSet.marks{i}(3)));
                
                app.ListBox.Items{end+1} = markName;
            end

            if ~isempty(imageSet.averageASL)
                app.ReferenceASLEditField.Value = imageSet.averageASL;
            end

            if ~isempty(imageSet.intensity)
                app.IntensityEditField.Value = imageSet.intensity;
            end

            if ~isempty(imageSet.ratio)
                app.IntensityRatioEditField.Value = imageSet.ratio;
            end

            if ~isempty(imageSet.calcASL)
                app.FinalASLEditField.Value = imageSet.calcASL;
            end

            app.NameLabel.Text        = imageSet.folderName;
            app.FrameEditField.Value  = frames;
            app.HeightEditField.Value = height;
            app.WidthEditField.Value  = width;
            app.ReferenceASLEditField.Value = imageSet.averageASL;
            app.FinalASLEditField.Value = imageSet.calcASL;

            app.ClearMarks();
            for i=1:numel(imageSet.marks)
                mk = imageSet.marks{i};
                markName = sprintf('Mark%d: [%d, %d]', i, round(mk(1)), round(mk(1) + mk(3)));

                app.ListBox.Items{end+1} = markName;
            end

            app.DisplayImage(app.UIAxes, app.Image_Current{1});

            app.Slider.Value    = 1;
            app.Spinner.Value   = 1;
            app.Slider.Limits   = [1 frames];
            app.Spinner.Limits  = [1 frames];
            app.SurfaceButton.Value = 1;
            app.Slider.Enable   = "on";
            app.Spinner.Enable  = "on";
        end
        
        function [Image_Segment, TopLayer_All, BottomLayer_All] = SegmentImage(app, TopLayer, BottomLayer, Image_Current)
            posUpper = TopLayer;
            posLower = BottomLayer;
            imsize   = size(Image_Current{1});

            % Segment top layer
            pos_ystart = posUpper(1,2);
            pos_yend   = posUpper(end,2);

            pos_xstart = [1, pos_ystart];
            pos_xend   = [imsize(2), pos_yend];
            layer      = [pos_xstart; posUpper; pos_xend];

            xq = 1:1:imsize(2);
            currLayer(:,1) = 1:imsize(2);
            currLayer(:,2) = interp1(layer(:,1), layer(:,2), xq, 'makima');

            segOffset = 10;

            [fig, bar] = app.CreateWaitbar("Segmentation in progress ...");
            Image_Segment = {};
            TopLayer_All  = {};

            for i=1:numel(Image_Current)
                im = rgb2gray(Image_Current{i});
                [image, TopLayer_All{i}] = LayerSeg(im, currLayer, 0, segOffset);
                
                app.DisplayImage(app.UIAxes, image);
                app.Slider.Value    = i;
                app.Spinner.Value   = i;
                drawnow;

                bar.Value = i/(numel(Image_Current)*2);
            end

            % Segment bottom layer
            pos_ystart = posLower(1,2);
            pos_yend   = posLower(end,2);

            pos_xstart = [1, pos_ystart];
            pos_xend   = [imsize(2), pos_yend];
            layer      = [pos_xstart; posLower; pos_xend];

            xq = 1:1:imsize(2);
            currLayer(:,1) = 1:imsize(2);
            currLayer(:,2) = interp1(layer(:,1), layer(:,2), xq, 'makima');

            BottomLayer_All = {};
            for i=1:numel(Image_Current)
                im = rgb2gray(Image_Current{i});
                [image, BottomLayer_All{i}] = LayerSeg(im, currLayer, 0, segOffset);
                
                app.DisplayImage(app.UIAxes, image);
                app.Slider.Value  = i;
                app.Spinner.Value = i;
                drawnow;

                bar.Value = (i+numel(Image_Current))/(numel(Image_Current)*2);
            end

            if isvalid(fig)
                close(fig);
            end

            for i = 1:numel(Image_Current)
                imTemp = insertShape( Image_Current{i}, 'line', [TopLayer_All{i}(:,1) TopLayer_All{i}(:,2)], ...
                               'Color', [255, 0, 0], 'LineWidth', 2, 'Opacity', 1);
            
                Image_Segment{i} = insertShape( imTemp, 'line', [BottomLayer_All{i}(:,1) BottomLayer_All{i}(:,2)], ...
                           'Color', [0, 255, 0], 'LineWidth', 2, 'Opacity', 1);
            end
        end
        
        function [TopLayer_Binary, BottomLayer_Binary, Image_Eq, EpiLayer, y_center] ...
                    = ProcessEq(app, Image_Current, TopLayerAll, BottomLayerAll)
            % y_center   = app.OffsetEditField.Value;
            y_center = 400;

            % eq_range   = app.EQRangeEditField.Value;
            eq_range = 20;

            % clip_limit = app.ClipLimitEditField.Value;
            clip_limit = 0.05;

            % dilate_lvl = app.DilateSpinner.Value;
            dilate_lvl = 2;

            [fig, bar] = app.CreateWaitbar("Enhancing the contrast...");
            Image_Processed = {};

            maxdiff = 0;

            height_seg = zeros(400, numel(Image_Current));

            % ******************** Step 1: 图像拉平 ******************** %
            for i = 1:numel(Image_Current)
                
                % 读取图像
                img = Image_Current{i};
                
                % 确保是灰度图
                if size(img, 3) == 3
                    img = rgb2gray(img);
                end
                
                % 获取当前图像的 segmentation 结果
                upper_data = TopLayerAll{i};  % 获取上部分的分割坐标 (n×2)
                lower_data = BottomLayerAll{i};  % 获取下部分的分割坐标 (n×2)
                [height, width] = size(img);
                
                % 遍历每个 A-line（竖线）
                for x = 1:width
                    % 获取该 A-line 在 upper 和 lower 中的 y1 和 y2
                    y1_rows = upper_data(:,1) == x;  % 查找 A-line 对应的行
                    y2_rows = lower_data(:,1) == x;  
            
                    if any(y1_rows)
                        y1 = max(upper_data(y1_rows, 2));  % 取最小的 y1
                    else
                        y1 = NaN;  % 没有数据，设为空
                    end
            
                    if any(y2_rows)
                        y2 = min(lower_data(y2_rows, 2));  % 取最大的 y2
                    else
                        y2 = NaN;
                    end
            
                    % 确保 y1, y2 在合法范围内
                    if isnan(y1) || isnan(y2) || y1 >= y2
                        continue;  % 跳过该 A-line
                    end
            
                    y1 = max(1, round(y1)); 
                    y2 = min(height, round(y2));
            
                    if (y2-y1) > maxdiff
                        maxdiff = y2-y1;
                    end

                    seg_diff = y2-y1;
                    if(y2-y1) < 0
                        seg_diff = 0;
                    end
                    height_seg(x, i) = seg_diff;

                    % 计算整体位移，使 y1 对齐到offset位置
                    shift  = y_center - y2;
                    img(:, x) = circshift(img(:, x), [shift, 0]);
                end
                
                Image_Processed{i} = img;

                bar.Value = i/numel(Image_Current);
            end

            if isvalid(fig)
                close(fig);
            end

            % ******************** Step 2: 图像平均 ******************** %
            numMatrices = numel(Image_Processed);
            
            % 初始化一个全零的矩阵用于存储总和
            sumMatrix = zeros(height, width);
            
            % 将所有矩阵的元素逐个加到 sumMatrix 中
            for i = 1:numMatrices
                sumMatrix = sumMatrix + double(Image_Processed{i});
            end
            
            % 计算平均值
            averageMatrix = sumMatrix / numMatrices;
            averageImage = uint8(averageMatrix);

            % ******************** Step 3: 自适应均值化 ******************** %
            im_eq = averageImage;
            height_seg = mean(height_seg, 2);
            
            for i=1:width
                l = max(1, i-eq_range);
                r = min(width, i+eq_range);
                if height_seg(i) <= 0
                    continue;
                end
                
                start_offset = round(width-height_seg(i));
                col = averageImage(start_offset:width, l:r);
                col = adapthisteq(col, "NumTiles", [4 4], 'ClipLimit', clip_limit);
            
                index = round((r-l)/2);
                im_eq(start_offset:width, i) = col(:, index);
            end

            % ******************** Step 4: 分割上皮上表面 ******************** %
            est_thickness = 28;
            sigma_base = 12;
            EpiLayer = app.segmentEpitheliumTop(im_eq, height_seg, (y_center-est_thickness), sigma_base);

            TopLayer_Binary = upper_data;
            BottomLayer_Binary = lower_data;

            Image_Eq = im_eq;
        end

        function [Layer_ASL] = CalcASL(~, Image_Eq, Image_Binary, TopLayer_Binary, BottomLayer_Binary)
            im_original = Image_Eq;
            im_binary   = Image_Binary;
            
            showProgress = 0;

            [~, aline] = size(im_original);

            upper_data = TopLayer_Binary;
            lower_data = BottomLayer_Binary;
            
            % 转换为灰度 RGB 图像
            im_original_RGB = cat(3, im_original, im_original, im_original);
            
            offset = 20;
            
            diff_layer = zeros(aline, 1, 'int32');

            for a=1:aline
                min_upper = min(upper_data(upper_data(:,1) == a, 2));
                min_lower = min(lower_data(lower_data(:,1) == a, 2));
            
                diff_layer(a, 1) = int32(min_lower - min_upper);
            
                if diff_layer(a, 1) > 0
                    im_original_RGB(offset+diff_layer(a,1), a, 1) = 255;
                    im_original_RGB(offset+diff_layer(a,1), a, 2) = 0;
                    im_original_RGB(offset+diff_layer(a,1), a, 3) = 0;
                end
            end
            
            % 标记二值图像中的所有连通区域
            cc = bwconncomp(im_binary);
            stats = regionprops(cc, 'Area'); 
            
            % 计算所有区域的平均面积
            areas = [stats.Area]; 
            meanArea = mean(areas); 
            
            % 生成新二值图，仅保留大区域，填充小区域为 0
            im_binary_fltr = im_binary;
            
            % 存储大区域的索引
            validRegions = [];
            
            % 遍历所有连通区域
            for i = 1:cc.NumObjects
                if areas(i) < meanArea
                    im_binary_fltr(cc.PixelIdxList{i}) = 0; % 填充该区域
                else
                    validRegions = [validRegions, i]; % 记录大区域
                end
            end
            
            if 1 == showProgress
                figure; imshow(im_binary_fltr);
                title('过滤后的二值图');
            end
            
            % 获取每个区域的列范围
            region_bounds = zeros(length(validRegions), 3); % [索引, 最小列, 最大列]
            
            for i = 1:length(validRegions)
                idx = validRegions(i);
                [rows, cols] = ind2sub(size(im_binary), cc.PixelIdxList{idx});
                region_bounds(i, :) = [idx, min(cols), max(cols)];
            end
            
            % **去除水平完全被覆盖的较小区域**
            to_remove = false(size(validRegions)); % 记录要删除的区域
            for i = 1:size(region_bounds, 1)
                for j = 1:size(region_bounds, 1)
                    if i ~= j % 避免自己和自己比较
                        % 获取两个区域的索引和列范围
                        idx_A = region_bounds(i, 1); col_min_A = region_bounds(i, 2); col_max_A = region_bounds(i, 3);
                        idx_B = region_bounds(j, 1); col_min_B = region_bounds(j, 2); col_max_B = region_bounds(j, 3);
                        
                        % 如果 A 在 B 的水平范围内，并且 B 的面积更大，删除 A
                        if col_min_B <= col_min_A && col_max_A <= col_max_B && areas(idx_A) < areas(idx_B)
                            to_remove(i) = true;
                        end
                    end
                end
            end
            
            % 过滤掉被移除的区域
            validRegions = validRegions(~to_remove);
            
            % **确保每一条 A-line（列）上都有连通区域**
            % 获取所有剩余区域的像素索引
            remaining_pixels = [];
            for i = validRegions
                remaining_pixels = [remaining_pixels; cc.PixelIdxList{i}]; 
            end
            
            % 生成最终的二值图
            im_binary_final = false(size(im_binary)); 
            im_binary_final(remaining_pixels) = 1; % 仅保留有效区域
            
            if 1 == showProgress
                figure; imshow(im_binary_final);
                title('去除被完全覆盖的小区域后的二值图');
            end
            
            % 提取每个剩下的连通区域的底部曲线
            bottom_curves = {}; % 存储底部曲线坐标
            
            for i = validRegions
                % 获取该区域的所有像素坐标
                [rows, cols] = ind2sub(size(im_binary), cc.PixelIdxList{i});
                
                % 找到每列的最底部点
                uniqueCols = unique(cols); % 获取该区域所有出现的列
                bottom_curve = [];
                
                for col = uniqueCols'
                    rowIndices = rows(cols == col); % 获取该列中的所有行索引
                    maxRow = max(rowIndices); % 选择最底部的点
                    bottom_curve = [bottom_curve; maxRow, col]; % 存储 (row, col)
                end
                
                % 存入曲线数组
                bottom_curves{end+1} = bottom_curve;
            end
            
            for a = 1:aline
                difference = diff_layer(a, 1);
            
                if difference > 0
                    im_binary_fltr(offset+difference, a) = 1;
                end
            end
            
            if 1 == showProgress
                figure; imshow(im_binary_fltr); hold on;
                for i = 1:length(bottom_curves)
                    plot(bottom_curves{i}(:,2), bottom_curves{i}(:,1), 'r-', 'LineWidth', 2); % 画出底部曲线
                end
                title('填充后图像 & 底部曲线');
                hold off;
            end
            
            asl_list = {};
            final_result = cell(aline, 1);
            for i = 1:length(bottom_curves)
                curve = bottom_curves{i};
                for a = 1:size(curve, 1)
                    idxA = curve(a, 2);
                    idxP = curve(a, 1);
            
                    final_result{idxA}{end+1} = idxP;
            
                    btm_pos = diff_layer(idxA, 1)+offset;
                    
                    asl_list{end+1} = max(btm_pos - idxP, 1);
                    
                    if idxP > btm_pos
                        % keyboard;   % 不应该出现这种情况
                    else
                        
                    end
                end
            end
            
            mean_asl = mean(cell2mat(asl_list));
            final_layer = zeros(aline, 1, 'int32');
            for a = 1:aline
                points = cell2mat(final_result{a});
                ref_point = double(diff_layer(a) - mean_asl);
            
                if ~isempty(points)
                    [m, n] = min(abs(points - ref_point));
                    final_layer(a) = points(n);
                end
            end
            
            % 获取原始曲线数据（假设A和B均为400x1列向量）
            A_filled = final_layer; % 初始化填充后的曲线
            A = final_layer;
            B = diff_layer(:); % 确保B为列向量
            
            % 检测缺失区域（连续0值段）
            zero_mask = (A == 0);
            edges = diff([0; zero_mask; 0]); % 边界检测
            region_starts = find(edges == 1);  % 缺失段起始索引
            region_ends = find(edges == -1)-1; % 缺失段结束索引
            
            % 遍历所有缺失区域
            for k = 1:length(region_starts)
                start_idx = region_starts(k);
                end_idx = region_ends(k);
                
                if start_idx == 1
                elseif end_idx == aline
                else
                    start_value = A_filled(start_idx-1);
                    end_value = A_filled(end_idx+1);

                    ref_line = diff_layer(start_idx:end_idx);

                    move_offset = start_value - ref_line(1);
                    ref_line = ref_line + move_offset;

                    end_offset = ref_line(end) - end_value;
                    interval = end_idx - start_idx;

                    for v=start_idx:end_idx
                        idx_ref = v-start_idx+1;
                        A_filled(v) = ref_line(idx_ref)-end_offset*((v-start_idx)/interval);
                    end
                end
            end

            if 1 == showProgress
                % 可选：可视化对比
                figure;
                subplot(211), plot(A), title('原始曲线A');
                subplot(212), plot(A_filled), title('填补后的曲线A');
                
                figure; imshow(im_binary_fltr); hold on;
                plot(1:numel(A_filled), A_filled, 'r-', 'LineWidth', 2); % 画出底部曲线
                title('填充后图像 & 底部曲线');
                hold off;

                figure; imshow(im_original); hold on;
                plot(1:numel(smoothed_A_filled), smoothed_A_filled, 'r-', 'LineWidth', 2); % 画出底部曲线
                title('填充后图像 & 底部曲线 （中值平均）');
                hold off;
            end
            
            smoothed_A_filled = medfilt1(double(A_filled), 5);
            Layer_ASL = smoothed_A_filled;
        end
        
        function addSubnode(app, inputName, nodeName)
            % 获取 Tree 的第一层子节点
            nodes = app.Tree.Children;
        
            % 遍历第一层节点，查找匹配的名字
            targetNode = [];
            for i = 1:numel(nodes)
                if strcmp(nodes(i).Text, inputName)
                    targetNode = nodes(i);
                    break;
                end
            end
        
            % 如果找到目标节点，则检查是否已存在相同的子节点
            if ~isempty(targetNode)
                % 获取目标节点的子节点
                subNodes = targetNode.Children;
                exists = false;
                
                % 遍历子节点，检查是否已存在同名子节点
                for j = 1:numel(subNodes)
                    if strcmp(subNodes(j).Text, nodeName)
                        exists = true;
                        break;
                    end
                end
                
                % 如果子节点不存在，则添加
                if ~exists
                    uitreenode(targetNode, 'Text', nodeName);
                end
            else
                uialert(app.ASLCalculatorUIFigure, ['Node "' inputName '" not found!'], 'Error');
            end
        end

        
        function updateListBox(app, rect, recIdx)
            % 获取新的矩形位置
            newPos = rect.Position;  % [x, y, width, height]
            left = round(newPos(1));
            right = round(newPos(1) + newPos(3));
            
            % 找到匹配项的索引
            imIdx = strcmp({app.folderStruct.folderName}, app.NameLabel.Text);

            app.folderStruct(imIdx).marks{recIdx} = rect.Position;
            
            % 更新 ListBox 里的文字
            app.ListBox.Items{app.Rectangles(recIdx).ListBoxIndex} ...
                = sprintf('Mark%d: [%d, %d]', app.Rectangles(recIdx).ListBoxIndex, left, right);
        end
        
        function [intensity, ratio] = CalcIntensity(~, ImageCurr, topLayerAll, bottomLayerAll, epiLayer, y_center)
            
            % 计算layer之间的mean intensity
            for i = 1:numel(ImageCurr)
                img = rgb2gray(ImageCurr{i});

                top = topLayerAll{i};
                bottom = bottomLayerAll{i};

                currValue1 = 0;
                currCount1 = 0;

                currValue2 = 0;
                currCount2 = 0;

                sumValue1 = 0;
                sumCount1 = 0;

                sumValue2 = 0;
                sumCount2 = 0;

                aline = size(img, 2);
                for y = 1:aline
                    % 找出第一列为 10 的行的索引
                    top_indices = find(top(:, 1) == y);
                    btm_indices = find(bottom(:, 1) == y);
                    
                    if isscalar(top_indices)
                        % 若只有一个满足条件的行，获取该行第二列的值
                        topIndex = uint32(top(top_indices, 2));
                    else
                        continue;
                    end

                    if isscalar(btm_indices)
                        % 若只有一个满足条件的行，获取该行第二列的值
                        btmIndex = uint32(bottom(btm_indices, 2));
                    else
                        continue;
                    end

                    midIndex = btmIndex - (y_center - epiLayer(y));

                    if midIndex >= btmIndex || (y_center - epiLayer(y)) <= 0
                        continue; % 不合理
                    end

                    value1 = sum(img(topIndex:midIndex, y));
                    count1 = midIndex - topIndex + 1;

                    value2 = sum(img(midIndex:btmIndex, y));
                    count2 = btmIndex - midIndex + 1;

                    disp(['Image: ' num2str(i) '  Column:' num2str(y)  '  Top:' num2str(topIndex)  '  Bottom:' num2str(btmIndex) '  Value1:'  num2str(value1) '  Count1:' num2str(count1) '  Intensity: ' num2str(value1/count1)]);

                    currValue1 = currValue1 + value1;
                    currCount1 = currCount1 + count1;

                    currValue2 = currValue2 + value2;
                    currCount2 = currCount2 + count2;
                end

                intensity = currValue1/currCount1;
                disp(['Curr: ' num2str(i) '  Intensity: ' num2str(intensity)]);

                sumValue1 = sumValue1 + currValue1;
                sumCount1 = sumCount1 + currCount1;

                sumValue2 = sumValue2 + currValue2;
                sumCount2 = sumCount2 + currCount2;
            end

            intensity = double(sumValue1) / double(sumCount1);
            
            ratio = intensity / ( double(sumValue2)/double(sumCount2) );
        end

        function top_positions = segmentEpitheliumTop(~, image, height_seg, z_base, sigma_base)
        % image: (Depth x Width), 下表面已拉平到 400 行
        % height_seg: 1xW 总厚度
        % z_base: 标量或 1xW 基准高度（相对图顶端的行索引），建议 ~ 400 - 上皮厚度
        % sigma_base: 基准的容忍宽度(像素)，建议 8~20
        %
        % 输出: 1xW 每列上皮上表面位置(行索引)
        
            [depth, width] = size(image);
            bottom = 400;                % 已知下表面在 400
            Hmax   = 120;                % 最大搜索厚度
            
            %kSmooth1D = 5;               % 列向平滑
            %gradWin = 3;                 % 梯度前再平滑
            kSmooth1D = 1;               % 列向平滑
            gradWin = 1;                 % 梯度前再平滑
            
            a = 0.6; b = 1.2;            % 梯度/对比的权重
            medWin = 10;                  % 结果平滑
            menWin = 12;
        
            % 预处理: 归一化到 [0,1]
            I = double(image);
            I = I - min(I(:));
            if max(I(:))>0, I = I./max(I(:)); end
        
            % z_base 形状统一
            if isscalar(z_base), z_base = repmat(z_base,1,width); end
            if nargin < 4 || isempty(sigma_base), sigma_base = 12; end
            sigma_base = max(sigma_base, 1);
        
            top_positions = nan(1, width);
        
            for x = 1:width
                Hx = height_seg(min(x,numel(height_seg)));
                if isnan(Hx) || Hx <= 3, Hx = Hmax; end
                Hsearch = min(Hx, Hmax);
        
                z0 = max(1, bottom - round(Hsearch) + 1);
                z1 = min(depth, bottom);
        
                col   = I(:,x);
                col_s = movmean(col, kSmooth1D, 1, 'omitnan');
        
                seg   = col_s(z0:z1);
                seg_s = movmean(seg, gradWin, 1, 'omitnan');
        
                g = diff(seg_s);                    % 向上差分
                
                % negGrad = max(-g, 0);               % 只取亮→暗的边
                posGrad = max(g, 0);   % 只取暗→亮的边
        
                % 局部对比 (上下各 win 像素)
                win = 4;
                n = numel(seg_s);
                upMean = nan(size(g)); dnMean = nan(size(g));
                for i = 1:numel(g)
                    zc = i;                         % seg_s 的索引 (对应全局 z=z0+i)
                    u0 = max(1,   zc-win); u1 = zc;
                    d0 = zc+1;     d1 = min(n, zc+win);
                    if u1>=u0 && d1>=d0
                        upMean(i) = mean(seg_s(u0:u1), 'omitnan');
                        dnMean(i) = mean(seg_s(d0:d1), 'omitnan');
                    end
                end
                dI = max(dnMean - upMean, 0);       % 下亮 - 上暗，负的置0
        
                % 基准先验权重 (高斯)
                z_cand = (z0:(z1-1));               % 与 g 对齐的全局 z
                w = exp( -((z_cand - z_base(x)).^2) / (2*sigma_base^2) );
        
                % 组合打分
                % S = w .* (a*negGrad + b*dI);
                w        = w(:);
                posGrad  = posGrad(:);
                dI       = dI(:);
                S = w .* (a*posGrad + b*dI);
        
                % 选择最大得分
                [Smax, idxLocal] = max(S(:));    % 确保取全局最大得分
                if isempty(Smax) || isnan(Smax) || Smax <= 0
                    top_positions(x) = nan;
                else
                    top_positions(x) = z0 + idxLocal - 1;   % 注意 max(S(:)) 索引要减1
                end
        
                Smax_rec(x) = Smax;
            end
        
            % 缺失列插值/兜底
            if any(isnan(top_positions))
                top_positions = fillmissing(top_positions, 'linear');
            end
            if any(isnan(top_positions))
                valid = ~isnan(top_positions);
                if any(valid)
                    H_epi = median(bottom - top_positions(valid));
                else
                    H_epi = round(median(min(height_seg, Hmax)));
                end
                top_positions(isnan(top_positions)) = bottom - H_epi;
            end
        
            % 物理约束：不高于腔面、不低于下表面
            for x = 1:width
                if x<=numel(height_seg) && ~isnan(height_seg(x))
                    top_positions(x) = max(top_positions(x), bottom - height_seg(x) + 1);
                    top_positions(x) = min(top_positions(x), bottom - 1);
                else
                    top_positions(x) = min(top_positions(x), bottom - 1);
                end
            end
        
            % 平滑
            top_positions = movmedian(top_positions, medWin);
            top_positions = movmean(top_positions,  menWin);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup(app)
            img = imread('icons/Background.jpg');  % 加载背景图
            app.DisplayImage(app.UIAxes, img);
        end

        % Button pushed function: ImportButton
        function ImportImage(app, event)
            folderPath = uigetdir; % 获取所有图片文件，优先加载子文件夹中的图片
            if folderPath == 0
                disp('未选择文件夹，退出程序。');
                return;
            end

            % 获取当前文件夹中的所有子文件夹
            files = dir(folderPath);
            subfolders = files([files.isdir]); % 仅选择文件夹
            subfolders = subfolders(~ismember({subfolders.name}, {'.', '..'})); % 过滤掉 . 和 ..

            app.folderStruct = [];
            folderInfo = [];
            % 加载子文件夹中的图片
            for i = 1:length(subfolders)
                subfolderPath = fullfile(folderPath, subfolders(i).name);
                [~, folderName] = fileparts(subfolderPath);

                imageFiles = dir(fullfile(subfolderPath, '*.bmp'));

                if isempty(imageFiles)
                    continue;
                end

                imageList = cell(length(imageFiles));
                for k = 1:length(imageFiles)
                    imageList{k} = fullfile(subfolderPath, imageFiles(k).name);
                end
                
                folderInfo(i).folderName       = folderName;    % 文件名
                folderInfo(i).imageFiles       = imageList;     % 原始图像路径
                folderInfo(i).topLayer         = [];            % reference top layer
                folderInfo(i).bottomLayer      = [];            % reference bottom layer
                folderInfo(i).image_segment    = {};            % 分割好的图像
                folderInfo(i).image_asl        = {};            % 分割结果-ASL
                folderInfo(i).image_equalized  = [];            % 均衡化图片
                folderInfo(i).image_average    = [];            % 平均后的图片
                folderInfo(i).image_sd         = [];            % 平均后的图片 (standard deviation)
                folderInfo(i).topLayerAll      = {};            % 分割结果-上层
                folderInfo(i).bottomLayerAll   = {};            % 分割结果-下层
                folderInfo(i).eqLayer          = [];
                folderInfo(i).marks            = {};            % 手动添加的计算区域
                folderInfo(i).averageASL       = 0;             % 平均参考ASL
                folderInfo(i).calcASL          = 0;             % 计算得到的ASL
                folderInfo(i).intensity        = 0;
                folderInfo(i).ratio            = 0;
            end

            % 加载主文件夹中的图片
            [~, folderName] = fileparts(folderPath);
            imageFiles = dir(fullfile(folderPath, '*.bmp'));
            imageList = cell(length(imageFiles));
            if ~isempty(imageFiles)
                for k = 1:length(imageFiles)
                    imageList{k} = fullfile(folderPath, imageFiles(k).name);
                end
            end

            folderIdx = length(subfolders)+1;
            folderInfo(folderIdx).folderName       = folderName;
            folderInfo(folderIdx).imageFiles       = imageList;
            folderInfo(folderIdx).topLayer         = [];            % reference top layer
            folderInfo(folderIdx).bottomLayer      = [];            % reference bottom layer
            folderInfo(folderIdx).image_segment    = {};            % 分割好的图像
            folderInfo(folderIdx).image_asl        = {};            % 分割结果-ASL
            folderInfo(folderIdx).image_equalized  = [];            % 均衡化图片
            folderInfo(folderIdx).image_average    = [];            % 平均后的图片
            folderInfo(folderIdx).image_sd         = [];            % 平均后的图片 (standard deviation)
            folderInfo(folderIdx).topLayerAll      = {};            % 分割结果-上层
            folderInfo(folderIdx).bottomLayerAll   = {};            % 分割结果-下层
            folderInfo(folderIdx).marks            = {};            % 手动添加的计算区域
            folderInfo(folderIdx).averageASL       = 0;             % 平均参考ASL
            folderInfo(folderIdx).calcASL          = 0;             % 计算得到的ASL
            folderInfo(folderIdx).intensity        = 0;
            folderInfo(folderIdx).ratio            = 0;

            % 过滤掉 `imageFiles` 为空的结构体
            folderInfo = folderInfo(~arrayfun(@(s) isempty(s.folderName), folderInfo));

            % 重置树节点
            delete(app.Tree.Children);
            for i = 1:length(folderInfo)
                folderNode = uitreenode(app.Tree, 'Text', folderInfo(i).folderName);
                uitreenode(folderNode, 'Text', 'Original', 'Icon', '');

                % 绑定上下文菜单到节点
                folderNode.ContextMenu = app.ContextDelete;
            end

            app.folderStruct = folderInfo;
            app.setsLabel.Text = sprintf('  %d set(s)', length(folderInfo));
            expand(app.Tree);
        end

        % Menu selected function: DeleteMenu
        function DeleteNode(app, event)
            inputName = event.ContextObject.Text;
            idx = strcmp({app.folderStruct.folderName}, inputName); % 找到匹配项的索引
            app.folderStruct(idx) = [];

            delete(event.ContextObject);

            msg = sprintf('  %d set(s)', length(app.folderStruct));
            app.setsLabel.Text = msg;
        end

        % Menu selected function: ExpandMenu
        function ExpandNode(app, event)
            expand(app.Tree);
        end

        % Menu selected function: CollapseMenu
        function CollapseNode(app, event)
            collapse(app.Tree);
        end

        % Selection changed function: Tree
        function SelectNode(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
            if selectedNodes.Parent == app.Tree
                % 主节点，忽略
            else
                inputName = selectedNodes.Parent.Text;
                app.LoadImage(inputName, selectedNodes.Text);
            end
        end

        % Value changed function: PlayButton
        function PlayImages(app, event)
            if isempty(app.Image_Current)
                app.PlayButton.Value = 0;
                return;
            end

            app.Playing = app.PlayButton.Value;
            if 1 == app.Playing
                app.PlayButton.Text = "Pause";
                app.PlayButton.Icon = "icons/pause.png";
                app.PlayingImages();
            else
                app.PlayButton.Text = "Play";
                app.PlayButton.Icon = "icons/play.png";
            end
        end

        % Button pushed function: SegmentButton
        function DrawLayer(app, event)
            if isempty(app.Image_Current)
                warndlg('No images loaded');
                return;
            end

            idx = strcmp({app.folderStruct.folderName}, app.NameLabel.Text);

            if true == app.SurfaceButton.Value
                if ~isempty(app.Layer_Top)
                    delete(app.Layer_Top);
                end

                targetColor = [0.3010 0.7450 0.9330];
                target_polylines = findobj(app.UIAxes, 'Type', 'images.roi.polyline', 'Color', targetColor);
                delete(target_polylines);

                app.Layer_Top = drawpolyline(app.UIAxes, 'Color', targetColor);

                if isvalid(app.Layer_Top)
                    app.folderStruct(idx).topLayer = app.Layer_Top.Position;
                end
            else
                if ~isempty(app.Layer_Bottom)
                    delete(app.Layer_Bottom);
                end

                targetColor = [0 0.4470 0.7410];
                target_polylines = findobj(app.UIAxes, 'Type', 'images.roi.polyline', 'Color', targetColor);
                delete(target_polylines);

                app.Layer_Bottom = drawpolyline(app.UIAxes, 'Color', [0 0.4470 0.7410]);

                if isvalid(app.Layer_Bottom)
                    app.folderStruct(idx).bottomLayer = app.Layer_Bottom.Position;
                end
            end
        end

        % Button pushed function: ProcessButton
        function ProcessAll(app, event)
            count = 0;
            idxList = {};
            for i=1:numel(app.folderStruct)
                node = app.Tree.Children(i);

                if ~isempty(app.folderStruct(i).topLayer) & ~isempty(app.folderStruct(i).bottomLayer)
                    count = count + 1;
                    idxList{end+1} = i;

                    if strcmp(node.Text, app.folderStruct(i).folderName) % 找到匹配的节点
                        node.Icon = 'slrtDoneIcon.png'; % 设置 icon
                    end
                end
            end
            
            if count == 0
                warndlg('Please do segmentation before calculation');
                return;
            end

            message = sprintf(['Segmentation progress (%d/%d). ' ...
                'Do you want to continue?'], count, numel(app.folderStruct));
            
            choice = questdlg( message, 'Confirmation', 'Yes', 'No', 'No');
            
            if strcmp(choice, 'Yes')
                try
                    % 继续执行后续代码
                    for i = 1:numel(idxList)
                        setIdx = idxList{i};
    
                        % 第一步：Load图像
                        app.LoadImage(app.folderStruct(setIdx).folderName, 'Original');
    
                        % 第二步：图像分割+拉平+平均
                        [app.folderStruct(setIdx).image_segment, app.folderStruct(setIdx).topLayerAll, app.folderStruct(setIdx).bottomLayerAll] ... 
                            = app.SegmentImage(app.folderStruct(setIdx).topLayer, app.folderStruct(setIdx).bottomLayer, app.Image_Current);
    
                        app.addSubnode(app.folderStruct(setIdx).folderName, 'Segment');
    
                        % 第三步：均衡化
                        [topLayer_Binary, bottomLayer_Binary, image_Eq, EpiLayer, y_center] ...
                            = app.ProcessEq(app.Image_Current, app.folderStruct(setIdx).topLayerAll, app.folderStruct(setIdx).bottomLayerAll);
    
                        % 第四步：提取ASL分割曲线
                        % layer_ASL = app.CalcASL(image_Eq, image_Binary, topLayer_Binary, bottomLayer_Binary);
    
                        % 第五步：计算intensity
                        [app.folderStruct(setIdx).intensity, app.folderStruct(setIdx).ratio] = app.CalcIntensity( ...
                            app.Image_Current, app.folderStruct(setIdx).topLayerAll, app.folderStruct(setIdx).bottomLayerAll, EpiLayer, y_center );

                        top_offset = 20;
    
                        Image_ASL = {};
                        [~, aline] = size(image_Eq);
                        frameNumber = numel(app.Image_Current);
                        for j = 1:frameNumber
                            segResult_btm = app.folderStruct(setIdx).bottomLayerAll{j};
                            result_currImage = zeros(aline, 1, 'uint16');
                            
                            currImage = app.Image_Current{j};
                            [~,~,channel] = size(currImage);
                            if channel == 1
                                currImage_RGB = cat(3, currImage, currImage, currImage);
                            else
                                currImage_RGB = currImage;
                            end
    
                            for a = 1:aline
                                min_btm = max(segResult_btm(segResult_btm(:,1) == a, 2));
                                
                                yCoords(a) = min_btm - (y_center - EpiLayer(a));

                                %offset = max(0, (layer_ASL(a)-top_offset));
    
                                %result_currImage(a) = uint16(min_btm + offset);
                            end
                        
                            % 确保 y 坐标在合法范围 [1, size(bitmap,1)]
                            %yCoords = max(1, min(size(currImage_RGB, 1), result_currImage));
                        
                            % 生成 x 坐标
                            xCoords = (1:aline);  % x 默认是 1 到 400
                        
                            % 组合成 [x, y] 坐标对
                            points = [xCoords' yCoords'];
                        
                            % 画线
                            imgWithLine = insertShape(currImage_RGB, 'Line', reshape(points', 1, []), ...
                                                      'Color', 'red', 'LineWidth', 2);
    
                            Image_ASL{end+1} = imgWithLine;
                        end
    
                        % 在均衡化图像上画ASL分割曲线
                        %yCoords = max(1, min(size(image_Eq, 1), layer_ASL));
    
                        %xCoords = (1:aline)';
    
                        %points = [xCoords, yCoords];
    
                        %final_result = insertShape(image_Eq, 'Line', reshape(points', 1, []), ...
                                                  %'Color', 'red', 'LineWidth', 2);

                        final_result = image_Eq;
    
                        [pixel, aline, ~] = size(app.Image_Current{1});
                        image_original = zeros(pixel, aline, frameNumber, 'uint8');
    
                        for frameIdx=1:frameNumber
                            image_original(:,:,frameIdx) = app.Image_Current{frameIdx}(:,:,1);
                        end
    
                        windowSize = app.WindowEditField.Value;
    
                        % 取图像的移动平均图集
                        if true == app.MeanCheckBox.Value
                            averagedImages = movmean(image_original, windowSize, 3); % 在第三维度 (Frame) 方向上平滑
    
                            app.folderStruct(setIdx).image_average = uint8(averagedImages);
                            app.addSubnode(app.folderStruct(setIdx).folderName, 'Average');
                        end
                        
                        % 基于standard deviation取图像的移动平均图集
                        if true == app.StandardDeviationCheckBox.Value
                            stdProjection = movstd(double(image_original), windowSize, 0, 3); % 计算沿 Z 轴（第 3 维）的滑动标准差 0 表示无偏估计 (n-1)
                    
                            sdImages = uint8(255 * mat2gray(stdProjection)); % 归一化到 uint8 范围
    
                            app.folderStruct(setIdx).image_sd = uint8(sdImages);
                            app.addSubnode(app.folderStruct(setIdx).folderName, 'SD');
                        end
    
                        % 补充tree node
                        app.folderStruct(setIdx).image_asl = Image_ASL;
                        app.addSubnode(app.folderStruct(setIdx).folderName, 'ASL');
    
                        app.folderStruct(setIdx).image_equalized = final_result;
                        app.folderStruct(setIdx).eqLayer = EpiLayer;
                        app.addSubnode(app.folderStruct(setIdx).folderName, 'Equalized');
    
                        refASL = {};
                        % 计算ASL
                        for f = 1:frameNumber
                            segResult_top = app.folderStruct(setIdx).topLayerAll{f};
                            segResult_btm = app.folderStruct(setIdx).bottomLayerAll{f};

                            for a=1:aline
                                top_indices = find(segResult_top(:, 1) == a);
                                btm_indices = find(segResult_btm(:, 1) == a);
                                
                                if isscalar(top_indices)
                                    % 若只有一个满足条件的行，获取该行第二列的值
                                    topIndex = uint32(segResult_top(top_indices, 2));
                                else
                                    continue;
                                end
            
                                if isscalar(btm_indices)
                                    % 若只有一个满足条件的行，获取该行第二列的值
                                    btmIndex = uint32(segResult_btm(btm_indices, 2));
                                else
                                    continue;
                                end

                                curr_asl = (btmIndex - topIndex) - (y_center - EpiLayer(a));
                                curr_asl = max(0, curr_asl);
                                refASL{end+1} = curr_asl;
                            end
                        end
    
                        allData  = [refASL{:}];  % 展开 cell 数据为一个数组
                        finalASL = mean(allData);  % 计算总平均值
    
                        umPerPixel = (app.VetDimEdit_mm.Value * 1000) / (app.VetDimEdit_px.Value);
                        averageASL = umPerPixel * finalASL;
                        app.ReferenceASLEditField.Value = averageASL;
                        app.folderStruct(setIdx).averageASL = averageASL;
                        app.IntensityEditField.Value = app.folderStruct(setIdx).intensity;
                        app.IntensityRatioEditField.Value = app.folderStruct(setIdx).ratio;
                    end
                catch
                    warndlg('Error');
                end
            else
                return;  % 退出当前函数或脚本
            end
        end

        % Button pushed function: SaveNextButton
        function SaveAndNext(app, event)
            inputName = app.NameLabel.Text;
            
            idx = strcmp({app.folderStruct.folderName}, inputName);

            if isempty(app.folderStruct(idx).topLayer) | isempty(app.folderStruct(idx).bottomLayer)
                warndlg('Invalid segmentation');
                return;
            end

            % 当前node设置icon
            for i = 1:length(app.Tree.Children)
                node = app.Tree.Children(i);
                if strcmp(node.Text, inputName) % 找到匹配的节点
                    node.Icon = 'slrtDoneIcon.png'; % 设置 icon
                    break; % 找到后立即返回，提高效率
                end
            end

            % 找下一个没有分割完成的image set
            for i = 1:numel(app.folderStruct)
                if isempty(app.folderStruct(i).topLayer) | isempty(app.folderStruct(i).bottomLayer)
                    for j = 1:length(app.Tree.Children)
                        node = app.Tree.Children(j);
                        if strcmp(node.Text, app.folderStruct(i).folderName) % 找到匹配的节点
                            app.Tree.SelectedNodes = node;
                            break; % 找到后立即返回，提高效率
                        end
                    end
                    app.LoadImage(app.folderStruct(i).folderName, 'Original');
                    break;
                end
            end
        end

        % Button pushed function: AddMarkButton
        function AddMark(app, event)
            if isempty(app.folderStruct)
                return;
            end

            idx = strcmp({app.folderStruct.folderName}, app.NameLabel.Text);

            % 让用户在 UIAxes 上手动画一个矩形
            rect = drawrectangle(app.UIAxes);
            
            % 获取矩形的位置 [x, y, width, height]
            app.folderStruct(idx).marks{end+1} = rect.Position;
            
            markName = sprintf('Mark%d: [%d, %d]', ...
            numel(app.folderStruct(idx).marks), ...
            round(rect.Position(1)), ...
            round(rect.Position(1) + rect.Position(3)));
            
            app.ListBox.Items{end+1} = markName;

            % 4️⃣  存储矩形对象 & 监听位置变化
            idx = numel(app.Rectangles) + 1;
            app.Rectangles(idx).Rect = rect;
            app.Rectangles(idx).ListBoxIndex = numel(app.ListBox.Items);
            
            % 5️⃣  监听位置变化，自动更新 ListBox
            addlistener(rect, 'MovingROI', @(src, evt) updateListBox(app, src, idx));
        end

        % Button pushed function: ClearButton
        function ClearMarks(app, event)
            app.ListBox.Items = {};
            
            imIdx = strcmp({app.folderStruct.folderName}, app.NameLabel.Text);
            app.folderStruct(imIdx).marks = {};

            for i = 1:numel(app.Rectangles)
                delete(app.Rectangles(i).Rect);
            end

            app.Rectangles = [];
        end

        % Button pushed function: CalculateButton
        function AdjustASL(app, event)
            imIdx = strcmp({app.folderStruct.folderName}, app.NameLabel.Text);
            imageSet = app.folderStruct(imIdx);

            if isempty(imageSet.eqLayer)
                warndlg('No ASL result');
                return;
            end

            aline = app.WidthEditField.Value;
            
            % 初始化 logical 数组
            mask = false(1, aline);

            for i=1:numel(imageSet.marks)
                startPoint = round(imageSet.marks{i}(1));
                endPoint   = round(imageSet.marks{i}(1)+imageSet.marks{i}(3));
                mask(startPoint:endPoint) = true;
            end

            top_offset = 20;
            refASL = {};
            % 计算ASL
            for i = 1:aline
                if mask(i) == 1
                    if imageSet.eqLayer(i) > top_offset
                        refASL{end+1} = imageSet.eqLayer(i) - top_offset;
                    end
                end
            end

            if isempty(refASL)
                return;
            end

            allData  = [refASL{:}];  % 展开 cell 数据为一个数组
            calcASL  = mean(allData);  % 计算总平均值

            umPerPixel = (app.VetDimEdit_mm.Value * 1000) / (app.VetDimEdit_px.Value);
            calcASL    = umPerPixel * calcASL;
            app.FinalASLEditField.Value     = calcASL;
            app.folderStruct(imIdx).calcASL = calcASL;
        end

        % Value changing function: Slider
        function SliderChanging(app, event)
            imIdx = int16(event.Value);
            if imIdx > numel(app.Image_Current)
                return;
            end
            app.DisplayImage(app.UIAxes, app.Image_Current{imIdx});
            app.Spinner.Value = event.Value;
            drawnow;
        end

        % Value changed function: Spinner
        function SpinnerChanged(app, event)
            imIdx = int16(app.Spinner.Value);
            if imIdx > numel(app.Image_Current)
                return;
            end
            app.DisplayImage(app.UIAxes, app.Image_Current{imIdx});
            app.Slider.Value = app.Spinner.Value;
        end

        % Button pushed function: ExportButton
        function ExportImages(app, event)
            outputFolder = uigetdir('', 'Select export path');
            if outputFolder == 0
                return;
            end

            [fig,bar] = app.CreateWaitbar("Saving image file(s) ...");
            for idx = 1:numel(app.Image_Current)
                filename = sprintf('%d.bmp', idx);
                imwrite(app.Image_Current{idx}, fullfile(outputFolder, filename), 'bmp');
                bar.Value = idx/numel(app.Image_Current);
            end

            if isvalid(fig)
                close(fig);
            end

            msgbox('Images export successfully!', 'Info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ASLCalculatorUIFigure = uifigure('Visible', 'off');
            app.ASLCalculatorUIFigure.Name = 'ASL Calculator';
            app.ASLCalculatorUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'appIcon.png');
            app.GridLayout = uigridlayout(app.ASLCalculatorUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.GridLayout_Tab = uigridlayout(app.MainTab);
            app.ImportButton = uibutton(app.GridLayout_Tab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImage, true);
            app.ImportButton.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.GridLayout_Tab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImages, true);
            app.ExportButton.Icon = fullfile(pathToMLAPP, 'icons', 'export.png');
            app.ExportButton.Text = 'Export';
            app.PlayButton = uibutton(app.GridLayout_Tab, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayImages, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'icons', 'play.png');
            app.PlayButton.Text = 'Play';
            app.Panel_Seperator2 = uipanel(app.GridLayout_Tab);
            app.RulerUnitButtonGroup = uibuttongroup(app.GridLayout_Tab);
            app.RulerUnitButtonGroup.Title = 'Ruler Unit';
            app.PixelButton = uiradiobutton(app.RulerUnitButtonGroup);
            app.PixelButton.Text = 'Pixel';
            app.PixelButton.Value = true;
            app.MicrometerusButton = uiradiobutton(app.RulerUnitButtonGroup);
            app.MicrometerusButton.Text = 'Micrometer (us)';
            app.Panel_Seperator3 = uipanel(app.GridLayout_Tab);
            app.ProcessButton = uibutton(app.GridLayout_Tab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAll, true);
            app.ProcessButton.Icon = fullfile(pathToMLAPP, 'slrtProcessAllIcon.png');
            app.ProcessButton.Text = 'Process';
            app.ButtonGroup = uibuttongroup(app.GridLayout_Tab);
            app.SurfaceButton = uiradiobutton(app.ButtonGroup);
            app.SurfaceButton.Text = 'Surface';
            app.SurfaceButton.Value = true;
            app.BasementButton = uiradiobutton(app.ButtonGroup);
            app.BasementButton.Text = 'Basement';
            app.SegmentButton = uibutton(app.GridLayout_Tab, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @DrawLayer, true);
            app.SegmentButton.Icon = fullfile(pathToMLAPP, 'slrtSegmentIcon.png');
            app.SegmentButton.Text = 'Segment';
            app.SaveNextButton = uibutton(app.GridLayout_Tab, 'push');
            app.SaveNextButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAndNext, true);
            app.SaveNextButton.Icon = fullfile(pathToMLAPP, 'slrtSaveNextIcon.png');
            app.SaveNextButton.Text = 'Save&Next';
            app.Panel_Seperator1 = uipanel(app.GridLayout_Tab);
            app.Panel_Seperator1.Title = 'Panel';
            app.VerticalResolutionPanel = uipanel(app.GridLayout_Tab);
            app.VerticalResolutionPanel.Title = 'Vertical Resolution';
            app.GridLayout_VetRes = uigridlayout(app.VerticalResolutionPanel);
            app.pxLabel = uilabel(app.GridLayout_VetRes);
            app.pxLabel.Text = '(px)';
            app.VetDimEdit_px = uieditfield(app.GridLayout_VetRes, 'numeric');
            app.VetDimEdit_px.Limits = [0 Inf];
            app.VetDimEdit_px.ValueDisplayFormat = '%.0f';
            app.VetDimEdit_px.Value = 800;
            app.VetDimEdit_mm = uieditfield(app.GridLayout_VetRes, 'numeric');
            app.VetDimEdit_mm.Limits = [0 Inf];
            app.VetDimEdit_mm.ValueDisplayFormat = '%.3f';
            app.VetDimEdit_mm.Value = 0.711;
            app.mmLabel = uilabel(app.GridLayout_VetRes);
            app.mmLabel.Text = '(mm)';
            app.ReportButton = uibutton(app.GridLayout_Tab, 'push');
            app.ReportButton.Icon = fullfile(pathToMLAPP, 'slrtReportIcon.png');
            app.ReportButton.Text = 'Report';
            app.GridLayout3 = uigridlayout(app.GridLayout_Tab);
            app.WindowEditField = uieditfield(app.GridLayout3, 'numeric');
            app.WindowEditField.Limits = [1 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.Value = 20;
            app.MeanCheckBox = uicheckbox(app.GridLayout3);
            app.MeanCheckBox.Text = 'Mean';
            app.MeanCheckBox.Value = true;
            app.StandardDeviationCheckBox = uicheckbox(app.GridLayout3);
            app.StandardDeviationCheckBox.Text = 'Standard Deviation';
            app.StandardDeviationCheckBox.Value = true;
            app.Panel_Seperator4 = uipanel(app.GridLayout_Tab);
            app.Panel_Seperator5 = uipanel(app.GridLayout_Tab);
            app.Slider = uislider(app.GridLayout);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderChanging, true);
            app.Slider.Enable = 'off';
            app.Spinner = uispinner(app.GridLayout);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerChanged, true);
            app.Spinner.Enable = 'off';
            app.Spinner.Value = 1;
            app.setsLabel = uilabel(app.GridLayout);
            app.setsLabel.Text = '  0 set(s)';
            app.ImageSetsLabel = uilabel(app.GridLayout);
            app.ImageSetsLabel.Interpreter = 'html';
            app.ImageSetsLabel.Text = 'Image Sets';
            app.ImageViewLabel = uilabel(app.GridLayout);
            app.ImageViewLabel.Interpreter = 'html';
            app.ImageViewLabel.Text = 'Image View';
            app.InformationLabel = uilabel(app.GridLayout);
            app.InformationLabel.Interpreter = 'html';
            app.InformationLabel.Text = 'Information';
            app.GridLayout_Information = uigridlayout(app.GridLayout);
            app.FrameLabel = uilabel(app.GridLayout_Information);
            app.FrameLabel.Text = 'Frame:';
            app.FrameEditField = uieditfield(app.GridLayout_Information, 'numeric');
            app.FrameEditField.Limits = [0 Inf];
            app.FrameEditField.ValueDisplayFormat = '%.0f';
            app.FrameEditField.Editable = 'off';
            app.HeightEditFieldLabel = uilabel(app.GridLayout_Information);
            app.HeightEditFieldLabel.Text = 'Height:';
            app.HeightEditField = uieditfield(app.GridLayout_Information, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.ValueDisplayFormat = '%.0f';
            app.HeightEditField.Editable = 'off';
            app.WidthEditFieldLabel = uilabel(app.GridLayout_Information);
            app.WidthEditFieldLabel.Text = 'Width:';
            app.WidthEditField = uieditfield(app.GridLayout_Information, 'numeric');
            app.WidthEditField.Limits = [0 Inf];
            app.WidthEditField.ValueDisplayFormat = '%.0f';
            app.WidthEditField.Editable = 'off';
            app.ReferenceASLEditFieldLabel = uilabel(app.GridLayout_Information);
            app.ReferenceASLEditFieldLabel.Text = 'Reference ASL';
            app.ReferenceASLEditField = uieditfield(app.GridLayout_Information, 'numeric');
            app.ReferenceASLEditField.ValueDisplayFormat = '%.2f';
            app.umLabel = uilabel(app.GridLayout_Information);
            app.umLabel.Text = 'um';
            app.GridLayout_MarkZone = uigridlayout(app.GridLayout_Information);
            app.AddMarkButton = uibutton(app.GridLayout_MarkZone, 'push');
            app.AddMarkButton.ButtonPushedFcn = createCallbackFcn(app, @AddMark, true);
            app.AddMarkButton.Text = 'Add Mark';
            app.ClearButton = uibutton(app.GridLayout_MarkZone, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMarks, true);
            app.ClearButton.Text = 'Clear';
            app.ListBox = uilistbox(app.GridLayout_MarkZone);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.CalculateButton = uibutton(app.GridLayout_MarkZone, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustASL, true);
            app.CalculateButton.Text = 'Calculate';
            app.FinalASLEditFieldLabel = uilabel(app.GridLayout_Information);
            app.FinalASLEditFieldLabel.Text = 'Final ASL';
            app.FinalASLEditField = uieditfield(app.GridLayout_Information, 'numeric');
            app.FinalASLEditField.ValueDisplayFormat = '%.2f';
            app.umLabel_2 = uilabel(app.GridLayout_Information);
            app.umLabel_2.Text = 'um';
            app.NameLabel = uilabel(app.GridLayout_Information);
            app.NameLabel.Text = '-';
            app.IntensityEditFieldLabel = uilabel(app.GridLayout_Information);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.IntensityEditField = uieditfield(app.GridLayout_Information, 'numeric');
            app.IntensityEditField.ValueDisplayFormat = '%.2f';
            app.IntensityRatioEditFieldLabel = uilabel(app.GridLayout_Information);
            app.IntensityRatioEditFieldLabel.Text = 'Intensity Ratio';
            app.IntensityRatioEditField = uieditfield(app.GridLayout_Information, 'numeric');
            app.IntensityRatioEditField.ValueDisplayFormat = '%.2f';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @SelectNode, true);
            app.ContextDelete = uicontextmenu(app.ASLCalculatorUIFigure);
            app.DeleteMenu = uimenu(app.ContextDelete);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteNode, true);
            app.DeleteMenu.Text = 'Delete';
            app.ExpandMenu = uimenu(app.ContextDelete);
            app.ExpandMenu.MenuSelectedFcn = createCallbackFcn(app, @ExpandNode, true);
            app.ExpandMenu.Text = 'Expand';
            app.CollapseMenu = uimenu(app.ContextDelete);
            app.CollapseMenu.MenuSelectedFcn = createCallbackFcn(app, @CollapseNode, true);
            app.CollapseMenu.Text = 'Collapse';
            % Show the figure after all components are created
            app.ASLCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ASLCalculator

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ASLCalculatorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @Startup)
            else

                % Focus the running singleton app
                figure(runningApp.ASLCalculatorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ASLCalculatorUIFigure)
        end
    end
end