classdef RegCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RegCalculatorUIFigure    matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Tree                     matlab.ui.container.Tree
        ImageViewLabel           matlab.ui.control.Label
        ImageSetsLabel           matlab.ui.control.Label
        setsLabel                matlab.ui.control.Label
        Spinner                  matlab.ui.control.Spinner
        Slider                   matlab.ui.control.Slider
        TabGroup                 matlab.ui.container.TabGroup
        MainTab                  matlab.ui.container.Tab
        GridLayout_Tab           matlab.ui.container.GridLayout
        Panel_Seperator4         matlab.ui.container.Panel
        GridLayout4_2            matlab.ui.container.GridLayout
        LevelEditField           matlab.ui.control.NumericEditField
        LevelLabel               matlab.ui.control.Label
        Check_RegIndex           matlab.ui.control.CheckBox
        Text_RefIndex_Reg        matlab.ui.control.NumericEditField
        IndexLabel_2             matlab.ui.control.Label
        GridLayout_Filter        matlab.ui.container.GridLayout
        Check_AutoThres          matlab.ui.control.CheckBox
        Check_AutoIndex          matlab.ui.control.CheckBox
        Text_Thres               matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        Text_RefIndex            matlab.ui.control.NumericEditField
        IndexLabel               matlab.ui.control.Label
        VerticalResolutionPanel  matlab.ui.container.Panel
        GridLayout_VetRes        matlab.ui.container.GridLayout
        mmLabel                  matlab.ui.control.Label
        VetDimEdit_mm            matlab.ui.control.NumericEditField
        VetDimEdit_px            matlab.ui.control.NumericEditField
        pxLabel                  matlab.ui.control.Label
        Panel_Seperator1         matlab.ui.container.Panel
        RegisterButton           matlab.ui.control.Button
        FilterButton             matlab.ui.control.Button
        Panel_Seperator3         matlab.ui.container.Panel
        RulerUnitButtonGroup     matlab.ui.container.ButtonGroup
        MicrometerusButton       matlab.ui.control.RadioButton
        PixelButton              matlab.ui.control.RadioButton
        Panel_Seperator2         matlab.ui.container.Panel
        PlayButton               matlab.ui.control.StateButton
        ExportButton             matlab.ui.control.Button
        ImportButton             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        ContextDelete            matlab.ui.container.ContextMenu
        DeleteMenu               matlab.ui.container.Menu
        ExpandMenu               matlab.ui.container.Menu
        CollapseMenu             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        folderStruct;
        Playing       = 0;
        Image_Current = {};
        Curr_Index    = 0;
    end
    
    methods (Access = private)
        
        function [fig, wb] = CreateWaitbar(app, title)
            waitbarWidth  = 400;
            waitbarHeight = 80;

            left  = app.RegCalculatorUIFigure.Position(1)+app.RegCalculatorUIFigure.Position(3)/2-waitbarWidth/2;
            right = app.RegCalculatorUIFigure.Position(2)+app.RegCalculatorUIFigure.Position(4)/2-waitbarHeight/2;
            fig   = uifigure('NumberTitle', 'off', 'MenuBar', 'none', 'ToolBar', 'none', 'Resize', 'off', 'Name', title, ...
                           'Position', [left, right, waitbarWidth, waitbarHeight], 'WindowStyle','modal');

            wb = uiprogressdlg(fig);
            wb.Message = 'In progress ...';
        end
        
        function DisplayImage(~, axes, Image)
            dimension = ndims(Image);
            if dimension == 2
                Image = cat(3, Image, Image, Image);
                imagesc(axes, Image);
            elseif dimension == 3
                imagesc(axes, Image);
            elseif dimension == 4
                warndlg('Invalid image format');
            else
                warndlg('Invalid image format');
            end

            axis(axes, 'tight');
        end
        
        function PlayingImages(app)
            imIdx  = uint16(app.Spinner.Value);
            frames = numel(app.Image_Current);

            if imIdx == frames
                imIdx = 1;
            end

            for i=imIdx:frames
                if app.Playing == 0
                    break;
                end
                
                % 读取当前 UIAxes 的缩放状态
                xlimOld = app.UIAxes.XLim;
                ylimOld = app.UIAxes.YLim;

                app.DisplayImage(app.UIAxes, app.Image_Current{i});
                app.Slider.Value  = double(i);
                app.Spinner.Value = double(i);

                % 恢复之前的缩放状态
                app.UIAxes.XLim = xlimOld;
                app.UIAxes.YLim = ylimOld;
                drawnow;
            end

            app.PlayButton.Text  = "Play";
            app.PlayButton.Icon  = "icons\Play.png";
            app.PlayButton.Value = 0;
            app.Playing          = 0;
        end
        
        function result = LoadImage(app, InputName, ImageType)
            result = true;

            % 找到匹配项的索引
            idx = strcmp({app.folderStruct.folderName}, InputName);

            imageSet = app.folderStruct(idx); % 获取匹配的 struct
            app.Curr_Index = idx;

            app.Image_Current = {};

            switch ImageType
                case 'Original'
                    frames = numel(imageSet.imageFiles);
                case 'Filter'
                    frames = numel(imageSet.Image_Filter);
                case 'Register'
                    frames = numel(imageSet.Image_Register);
            end

            if frames == 0
                result = false;
                return;
            end

            [fig,bar] = app.CreateWaitbar('Loading image(s)...');

            heightRef = 0;
            widthRef  = 0;
            for i=1:frames
                switch ImageType
                    case 'Original'
                        imgFile = imageSet.imageFiles{i};
                        img = imread(imgFile);
                    case 'Filter'
                        img = imageSet.Image_Filter{i};
                    case 'Register'
                        img = imageSet.Image_Register{i};
                end
                
                [height, width, channel] = size(img);

                if heightRef~= 0
                    if heightRef ~= height
                        warndlg('Invalid image size');
                        if isvalid(fig)
                            close(fig);
                        end
                        return;
                    end
                else
                    heightRef = height;
                end

                if widthRef~= 0
                    if widthRef ~= width
                        warndlg('Invalid image size');
                        return;
                    end
                else
                    widthRef = width;
                end

                if channel == 1
                    app.Image_Current{i} = cat(3, img, img, img);
                else
                    app.Image_Current{i} = img;
                end

                bar.Value = i/frames;
            end

            if isvalid(fig)
                close(fig);
            end

            app.DisplayImage(app.UIAxes, app.Image_Current{1});

            app.Slider.Value  = 1;
            app.Spinner.Value = 1;

            if frames>1
                app.Slider.Limits   = [1 frames];
                app.Spinner.Limits  = [1 frames];
            end

            app.Slider.Enable   = "on";
            app.Spinner.Enable  = "on";
        end
        
        function addSubnode(app, inputName, nodeName)
            % 获取 Tree 的第一层子节点
            nodes = app.Tree.Children;
        
            % 遍历第一层节点，查找匹配的名字
            targetNode = [];
            for i = 1:numel(nodes)
                if strcmp(nodes(i).Text, inputName)
                    targetNode = nodes(i);
                    break;
                end
            end
        
            % 如果找到目标节点，则检查是否已存在相同的子节点
            if ~isempty(targetNode)
                % 获取目标节点的子节点
                subNodes = targetNode.Children;
                exists = false;
                
                % 遍历子节点，检查是否已存在同名子节点
                for j = 1:numel(subNodes)
                    if strcmp(subNodes(j).Text, nodeName)
                        exists = true;
                        break;
                    end
                end
                
                % 如果子节点不存在，则添加
                if ~exists
                    uitreenode(targetNode, 'Text', nodeName);
                end
            else
                uialert(app.RegCalculatorUIFigure, ['Node "' inputName '" not found!'], 'Error');
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Startup(app)
            % 设置默认图片文件路径
            imgPath = 'icons\Background.jpg';  % 替换成你的图片路径
            
            % 初始设置：确保 UIAxes 可点击
            app.UIAxes.PickableParts = 'all';
            app.UIAxes.HitTest = 'on';
            disableDefaultInteractivity(app.UIAxes); % 禁用默认交互

            % 使用 imshow 函数在 UIAxes 上显示图片
            img = imread(imgPath);  % 读取图片
            app.DisplayImage(app.UIAxes, img);
        end

        % Button pushed function: ImportButton
        function ImportImage(app, event)
            % 获取所有图片文件，优先加载子文件夹中的图片
            folderPath = uigetdir;
            if folderPath == 0
                disp('未选择文件夹，退出程序。');
                return;
            end
        
            % 获取当前文件夹中的所有子文件夹
            files = dir(folderPath);
            subfolders = files([files.isdir]); % 仅选择文件夹
            subfolders = subfolders(~ismember({subfolders.name}, {'.', '..'})); % 过滤掉 . 和 ..

            app.folderStruct = [];
            folderInfo = [];
            % 加载子文件夹中的图片
            for i = 1:length(subfolders)
                subfolderPath = fullfile(folderPath, subfolders(i).name);
                
                % 使用 strsplit 分割路径
                parts = strsplit(subfolderPath, '\');
                
                % 获取最后一部分
                folderName = parts{end};
                
                 %[~, folderName] = fileparts(subfolderPath);

                imageFiles = dir(fullfile(subfolderPath, '*.bmp'));

                % Sort files in number order
                sort_nat_name = sort_nat({image_dir.name});

                if isempty(imageFiles)
                    continue;
                end

                imageList = cell(length(imageFiles), 1);
                for k = 1:length(imageFiles)
                    imageList{k} = fullfile(subfolderPath, imageFiles(k).name);
                end
                
                folderInfo(i).folderName       = folderName;    % 文件名
                folderInfo(i).imageFiles       = imageList;     % 原始图像路径
                folderInfo(i).Image_Filter     = {};
                folderInfo(i).Image_Register   = {};
            end

            % 加载主文件夹中的图片
            [~, folderName] = fileparts(folderPath);
            imageFiles = dir(fullfile(folderPath, '*.bmp'));

            [imageFiles,~,~] = natsortfiles(imageFiles);

            imageList = cell(length(imageFiles), 1);
            if ~isempty(imageFiles)
                for k = 1:length(imageFiles)
                    imageList{k} = fullfile(folderPath, imageFiles(k).name);
                end
            end

            folderIdx = length(subfolders)+1;
            folderInfo(folderIdx).folderName       = folderName;
            folderInfo(folderIdx).imageFiles       = imageList;
            folderInfo(folderIdx).Image_Filter     = {};
            folderInfo(folderIdx).Image_Register   = {};

            % 过滤掉 `imageFiles` 为空的结构体
            folderInfo = folderInfo(~arrayfun(@(s) isempty(s.folderName), folderInfo));

            % 重置树节点
            delete(app.Tree.Children);
            for i = 1:length(folderInfo)
                folderNode = uitreenode(app.Tree, 'Text', folderInfo(i).folderName);
                uitreenode(folderNode, 'Text', 'Original', 'Icon', '');

                % 绑定上下文菜单到节点
                folderNode.ContextMenu = app.ContextDelete;
            end

            app.folderStruct = folderInfo;
            app.setsLabel.Text = sprintf('  %d set(s)', length(folderInfo));
            expand(app.Tree);
        end

        % Button pushed function: ExportButton
        function ExportImage(app, event)
            outputFolder = uigetdir('', 'Select export path');
            if outputFolder == 0
                return;
            end

            [fig,bar] = app.CreateWaitbar("Saving image file(s) ...");
            for idx = 1:numel(app.Image_Current)
                filename = sprintf('%d.bmp', idx);
                imwrite(app.Image_Current{idx}, fullfile(outputFolder, filename), 'bmp');
                bar.Value = idx/numel(app.Image_Current);
            end

            if isvalid(fig)
                close(fig);
            end

            msgbox('Images export successfully!', 'Info');
        end

        % Menu selected function: DeleteMenu
        function DeleteNode(app, event)
            inputName = event.ContextObject.Text;
            idx = strcmp({app.folderStruct.folderName}, inputName); % 找到匹配项的索引
            app.folderStruct(idx) = [];

            delete(event.ContextObject);

            msg = sprintf('  %d set(s)', length(app.folderStruct));
            app.setsLabel.Text = msg;
        end

        % Menu selected function: ExpandMenu
        function ExpandNode(app, event)
            expand(app.Tree);
        end

        % Menu selected function: CollapseMenu
        function CollapseNode(app, event)
            collapse(app.Tree);
        end

        % Selection changed function: Tree
        function SelectNode(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
            if selectedNodes.Parent == app.Tree
                % 主节点，忽略
            else
                inputName = selectedNodes.Parent.Text;
                app.LoadImage(inputName, selectedNodes.Text);
            end
        end

        % Value changed function: PlayButton
        function PlayImages(app, event)
            if isempty(app.Image_Current)
                app.PlayButton.Value = 0;
                return;
            end

            app.Playing = app.PlayButton.Value;
            if 1 == app.Playing
                app.PlayButton.Text = "Pause";
                app.PlayButton.Icon = "icons\Pause.png";
                app.PlayingImages();
            else
                app.PlayButton.Text = "Play";
                app.PlayButton.Icon = "icons\Play.png";
            end
        end

        % Value changing function: Slider
        function SliderChanging(app, event)
            imIdx = int16(event.Value);
            if imIdx > numel(app.Image_Current)
                return;
            end
            app.DisplayImage(app.UIAxes, app.Image_Current{imIdx});
            app.Spinner.Value = event.Value;
            drawnow;
        end

        % Value changed function: Spinner
        function SpinnerChanged(app, event)
            imIdx = int16(app.Spinner.Value);
            if imIdx > numel(app.Image_Current)
                return;
            end
            app.DisplayImage(app.UIAxes, app.Image_Current{imIdx});
            app.Slider.Value = app.Spinner.Value;
        end

        % Button pushed function: FilterButton
        function FilterImage(app, event)
            if isempty(app.Image_Current)
                msgbox('No images loaded!', '', 'error');
                return;
            end

            frames = numel(app.Image_Current);
            if true == app.Check_AutoIndex.Value
                [fig,bar] = app.CreateWaitbar('Searching reference image...');
                sum_images = double(rgb2gray(app.Image_Current{1}));
                for idx = 2:frames
                    sum_images = sum_images + double(rgb2gray(app.Image_Current{idx}));
                    bar.Value = idx/(frames*2);
                end
                average_image = uint8(sum_images / frames);
    
                correlation_values = zeros(1, frames);
                for idx = 1:frames
                    correlation_values(idx) = corr2(average_image, rgb2gray(app.Image_Current{idx}));
                    bar.Value = (frames+idx)/(frames*2);
                end
    
                [~, refIdx] = max(correlation_values);
                app.Text_RefIndex.Value = refIdx;
                drawnow;

                if isvalid(fig)
                    close(fig);
                end
            else
                refIdx = str2double(app.Text_RefIndex.Value);

                if isnan(refIdx)
                    msgbox('Invalid reference index', '', 'warn');
                    return;
                else
                    refIdx = int16(refIdx);
                end
            end

            corrAll = zeros(1, frames);
            imBase  = rgb2gray(uint8(app.Image_Current{refIdx}));
    
            % 执行相关性分析
            [fig,bar] = app.CreateWaitbar('Filtering in progress...');
            for idx = 1:frames
                image1 = imBase;
                image2 = rgb2gray(uint8(app.Image_Current{idx}));
                
                correlation = corr2(image1, image2);
                corrAll(idx)  = correlation;

                bar.Value = idx/frames;
            end

            if true == app.Check_AutoThres.Value
                thres = mean(corrAll);
            else
                thres = app.Text_Thres.Value;
                if thres > 1 || thres < 0
                    msgbox('Invalid threshold', '', 'warn');
                    return;
                end
            end
            if isvalid(fig)
                close(fig);
            end

            size_prev = numel(app.Image_Current);
            app.Image_Current  = app.Image_Current(corrAll > thres);
            size_new = numel(app.Image_Current);

            figure;
            plot(corrAll);
            hold on;
            plot([0, frames], [thres, thres], 'r', 'LineWidth', 2);
            hold off;
            title(sprintf('%d frames → %d frames', size_prev, size_new));

            app.Slider.Limits  = [1 numel(app.Image_Current)];
            app.Spinner.Limits = [1 numel(app.Image_Current)];
            app.Slider.Value   = 1;
            app.Spinner.Value  = 1;

            app.DisplayImage(app.UIAxes, app.Image_Current{1});
            axis(app.UIAxes, 'tight');

            app.Text_Thres.Value = thres;
            app.addSubnode(app.folderStruct(app.Curr_Index).folderName, 'Filter');
            app.folderStruct(app.Curr_Index).Image_Filter = app.Image_Current;
        end

        % Button pushed function: RegisterButton
        function RegisterImage(app, event)
            for idx = 1:numel(app.folderStruct)

                name = app.Tree.Children(idx).Text;

                if true == app.LoadImage(name, 'Filter')
                elseif true == app.LoadImage(name, 'Original')
                else
                    continue;
                end

                imageList = app.Image_Current;
                image_registered = cell(1, numel(imageList));
    
                frames = numel(imageList);
                % 选择fixed image
                if true == app.Check_RegIndex.Value
                    [fig,bar] = app.CreateWaitbar('Searching fixed image...');
                    sum_images = double(rgb2gray(app.Image_Current{1}));
                    for idx = 2:frames
                        sum_images = sum_images + double(rgb2gray(app.Image_Current{idx}));
                        bar.Value = idx/(frames*2);
                    end
                    average_image = uint8(sum_images / frames);
        
                    correlation_values = zeros(1, frames);
                    for idx = 1:frames
                        correlation_values(idx) = corr2(average_image, rgb2gray(app.Image_Current{idx}));
                        bar.Value = (frames+idx)/(frames*2);
                    end
        
                    [~, refIdx] = max(correlation_values);
                    app.Text_RefIndex_Reg.Value = refIdx;
                    drawnow;
    
                    if isvalid(fig)
                        close(fig);
                    end
                else
                    refIdx = str2double(app.Text_RefIndex_Reg.Value);
    
                    if isnan(refIdx)
                        msgbox('Invalid reference index', '', 'warn');
                        return;
                    else
                        refIdx = int16(refIdx);
                    end
                end
    
                % 初始化进度条
                h = waitbar(0, 'Processing images...');
    
                delete(gcp('nocreate'));
                parpool('local');  % 启动多线程池
                
                % 创建DataQueue并定义回调函数
                D = parallel.pool.DataQueue;
                afterEach(D, @(~) updateWaitbar(h, numel(imageList), false));
    
                fixedImage = im2gray(imageList{refIdx});
    
                pyramidLevels = app.LevelEditField.Value;
                
                updateWaitbar(h, numel(imageList), true);
    
                % 循环处理每张图像
                parfor i = 1:numel(imageList)
                    
                    % 移动图像
                    moving = im2gray(imageList{i});
                    fixed  = fixedImage;
                    
                    % 执行弹性配准
                    D_Reg = imregdemons(moving, fixed, ...
                        'AccumulatedFieldSmoothing', 1.5, ... % 形变场平滑系数
                        'PyramidLevels', pyramidLevels, ...   % 多尺度金字塔层数
                        'DisplayWaitbar', false);             % 关闭内置进度条
                    
                    % 应用形变场
                    image_registered{i} = imwarp(moving, D_Reg, 'bicubic');
    
                    % 发送进度更新信号
                    send(D, 1);
                end
                
                if isvalid(h)
                    close(h);
                end
                disp('配准完成！');
    
                app.Image_Current  = image_registered;
    
                app.Slider.Limits  = [1 numel(app.Image_Current)];
                app.Spinner.Limits = [1 numel(app.Image_Current)];
                app.Slider.Value   = 1;
                app.Spinner.Value  = 1;
    
                app.DisplayImage(app.UIAxes, app.Image_Current{1});
                axis(app.UIAxes, 'tight');
    
                app.addSubnode(app.folderStruct(app.Curr_Index).folderName, 'Register');
                app.folderStruct(app.Curr_Index).Image_Register = app.Image_Current;

            end
        end

        % Value changed function: Check_AutoThres
        function FltrThres_Checked(app, event)
            value = app.Check_AutoThres.Value;
            
            if true == value
                app.Text_Thres.Editable = false;
            else
               app.Text_Thres.Editable = true; 
            end
        end

        % Value changed function: Check_AutoIndex
        function FltrIndex_Checked(app, event)
            value = app.Check_AutoIndex.Value;

            if true == value
                app.Text_RefIndex.Editable = false;
            else
               app.Text_RefIndex.Editable = true; 
            end
        end

        % Value changed function: Check_RegIndex
        function FltrIndex_Reg_Checked(app, event)
            value = app.Check_RegIndex.Value;
            
            if true == value
                app.Text_RefIndex_Reg.Editable = false;
            else
               app.Text_RefIndex_Reg.Editable = true; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RegCalculatorUIFigure = uifigure('Visible', 'off');
            app.RegCalculatorUIFigure.Name = 'Reg Calculator';
            app.RegCalculatorUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'appIcon.png');
            app.GridLayout = uigridlayout(app.RegCalculatorUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.GridLayout_Tab = uigridlayout(app.MainTab);
            app.ImportButton = uibutton(app.GridLayout_Tab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImage, true);
            app.ImportButton.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.GridLayout_Tab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportImage, true);
            app.ExportButton.Icon = fullfile(pathToMLAPP, 'icons', 'export.png');
            app.ExportButton.Text = 'Export';
            app.PlayButton = uibutton(app.GridLayout_Tab, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayImages, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'icons', 'play.png');
            app.PlayButton.Text = 'Play';
            app.Panel_Seperator2 = uipanel(app.GridLayout_Tab);
            app.RulerUnitButtonGroup = uibuttongroup(app.GridLayout_Tab);
            app.RulerUnitButtonGroup.Title = 'Ruler Unit';
            app.PixelButton = uiradiobutton(app.RulerUnitButtonGroup);
            app.PixelButton.Text = 'Pixel';
            app.PixelButton.Value = true;
            app.MicrometerusButton = uiradiobutton(app.RulerUnitButtonGroup);
            app.MicrometerusButton.Text = 'Micrometer (us)';
            app.Panel_Seperator3 = uipanel(app.GridLayout_Tab);
            app.FilterButton = uibutton(app.GridLayout_Tab, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterImage, true);
            app.FilterButton.Icon = fullfile(pathToMLAPP, 'icons', 'filter.png');
            app.FilterButton.Text = 'Filter';
            app.RegisterButton = uibutton(app.GridLayout_Tab, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterImage, true);
            app.RegisterButton.Icon = fullfile(pathToMLAPP, 'icons', 'Register.png');
            app.RegisterButton.Text = 'Register';
            app.Panel_Seperator1 = uipanel(app.GridLayout_Tab);
            app.Panel_Seperator1.Title = 'Panel';
            app.VerticalResolutionPanel = uipanel(app.GridLayout_Tab);
            app.VerticalResolutionPanel.Title = 'Vertical Resolution';
            app.GridLayout_VetRes = uigridlayout(app.VerticalResolutionPanel);
            app.pxLabel = uilabel(app.GridLayout_VetRes);
            app.pxLabel.Text = '(px)';
            app.VetDimEdit_px = uieditfield(app.GridLayout_VetRes, 'numeric');
            app.VetDimEdit_px.Limits = [0 Inf];
            app.VetDimEdit_px.ValueDisplayFormat = '%.0f';
            app.VetDimEdit_px.Value = 800;
            app.VetDimEdit_mm = uieditfield(app.GridLayout_VetRes, 'numeric');
            app.VetDimEdit_mm.Limits = [0 Inf];
            app.VetDimEdit_mm.ValueDisplayFormat = '%.3f';
            app.VetDimEdit_mm.Value = 0.711;
            app.mmLabel = uilabel(app.GridLayout_VetRes);
            app.mmLabel.Text = '(mm)';
            app.GridLayout_Filter = uigridlayout(app.GridLayout_Tab);
            app.IndexLabel = uilabel(app.GridLayout_Filter);
            app.IndexLabel.Text = 'Index:';
            app.Text_RefIndex = uieditfield(app.GridLayout_Filter, 'numeric');
            app.Text_RefIndex.Limits = [1 Inf];
            app.Text_RefIndex.ValueDisplayFormat = '%.0f';
            app.Text_RefIndex.Editable = 'off';
            app.Text_RefIndex.Value = 1;
            app.ThresholdEditFieldLabel = uilabel(app.GridLayout_Filter);
            app.ThresholdEditFieldLabel.Text = 'Threshold:';
            app.Text_Thres = uieditfield(app.GridLayout_Filter, 'numeric');
            app.Text_Thres.Limits = [0 1];
            app.Text_Thres.ValueDisplayFormat = '%.3f';
            app.Text_Thres.Editable = 'off';
            app.Check_AutoIndex = uicheckbox(app.GridLayout_Filter);
            app.Check_AutoIndex.ValueChangedFcn = createCallbackFcn(app, @FltrIndex_Checked, true);
            app.Check_AutoIndex.Text = 'Auto';
            app.Check_AutoIndex.Value = true;
            app.Check_AutoThres = uicheckbox(app.GridLayout_Filter);
            app.Check_AutoThres.ValueChangedFcn = createCallbackFcn(app, @FltrThres_Checked, true);
            app.Check_AutoThres.Text = 'Auto';
            app.Check_AutoThres.Value = true;
            app.GridLayout4_2 = uigridlayout(app.GridLayout_Tab);
            app.IndexLabel_2 = uilabel(app.GridLayout4_2);
            app.IndexLabel_2.Text = 'Index:';
            app.Text_RefIndex_Reg = uieditfield(app.GridLayout4_2, 'numeric');
            app.Text_RefIndex_Reg.Limits = [1 Inf];
            app.Text_RefIndex_Reg.ValueDisplayFormat = '%.0f';
            app.Text_RefIndex_Reg.Editable = 'off';
            app.Text_RefIndex_Reg.Value = 1;
            app.Check_RegIndex = uicheckbox(app.GridLayout4_2);
            app.Check_RegIndex.ValueChangedFcn = createCallbackFcn(app, @FltrIndex_Reg_Checked, true);
            app.Check_RegIndex.Text = 'Auto';
            app.Check_RegIndex.Value = true;
            app.LevelLabel = uilabel(app.GridLayout4_2);
            app.LevelLabel.Text = 'Level:';
            app.LevelEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.LevelEditField.Limits = [1 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.Value = 8;
            app.Panel_Seperator4 = uipanel(app.GridLayout_Tab);
            app.Slider = uislider(app.GridLayout);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderChanging, true);
            app.Slider.Enable = 'off';
            app.Spinner = uispinner(app.GridLayout);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerChanged, true);
            app.Spinner.Enable = 'off';
            app.Spinner.Value = 1;
            app.setsLabel = uilabel(app.GridLayout);
            app.setsLabel.Text = '  0 set(s)';
            app.ImageSetsLabel = uilabel(app.GridLayout);
            app.ImageSetsLabel.Interpreter = 'html';
            app.ImageSetsLabel.Text = 'Image Sets';
            app.ImageViewLabel = uilabel(app.GridLayout);
            app.ImageViewLabel.Interpreter = 'html';
            app.ImageViewLabel.Text = 'Image View';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @SelectNode, true);
            app.ContextDelete = uicontextmenu(app.RegCalculatorUIFigure);
            app.DeleteMenu = uimenu(app.ContextDelete);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteNode, true);
            app.DeleteMenu.Text = 'Delete';
            app.ExpandMenu = uimenu(app.ContextDelete);
            app.ExpandMenu.MenuSelectedFcn = createCallbackFcn(app, @ExpandNode, true);
            app.ExpandMenu.Text = 'Expand';
            app.CollapseMenu = uimenu(app.ContextDelete);
            app.CollapseMenu.MenuSelectedFcn = createCallbackFcn(app, @CollapseNode, true);
            app.CollapseMenu.Text = 'Collapse';
            % Show the figure after all components are created
            app.RegCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RegCalculator

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RegCalculatorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @Startup)
            else

                % Focus the running singleton app
                figure(runningApp.RegCalculatorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RegCalculatorUIFigure)
        end
    end
end